classdef Calc_App_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel          matlab.ui.container.Panel
        Button         matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_11      matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_12      matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_10      matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        ClearButton_2  matlab.ui.control.Button
        Button_13      matlab.ui.control.Button
        Button_14      matlab.ui.control.Button
        sinButton      matlab.ui.control.Button
        tanButton      matlab.ui.control.Button
        sqrtButton     matlab.ui.control.Button
        CEButton       matlab.ui.control.Button
        Button_19      matlab.ui.control.Button
        cosButton      matlab.ui.control.Button
        AnsButton      matlab.ui.control.Button
        Button_22      matlab.ui.control.Button
        xButton        matlab.ui.control.Button
        Label          matlab.ui.control.Label
        EditField      matlab.ui.control.EditField
        Label_3        matlab.ui.control.Label
        answer         matlab.ui.control.EditField
        Label_2        matlab.ui.control.Label
        hide           matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           %app.TextArea.Value =  [app.TextArea.Value{1} event.Source.Text]
          bb = app.EditField.Value;
          bb = [bb '1'];
          app.EditField.Value = bb
        end

        % Callback function
        function Button_3Pushed(app, event)
           app.TextArea.Value =  [app.TextArea.Value{2} event.Source.Text]
        end

        % Callback function
        function Button_4Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{3} event.Source.Text]
        end

        % Callback function
        function Button_2Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{4} event.Source.Text]
        end

        % Callback function
        function Button_5Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{5} event.Source.Text]
        end

        % Callback function
        function Button_6Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{6} event.Source.Text]
        end

        % Callback function
        function Button_7Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{7} event.Source.Text]
        end

        % Callback function
        function Button_8Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{8} event.Source.Text]
        end

        % Callback function
        function Button_9Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{9} event.Source.Text]
        end

        % Callback function
        function Button_10Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{0} event.Source.Text]
        end

        % Callback function
        function Button_14Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{"/"} event.Source.Text]
        end

        % Callback function
        function Button_12Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{"-"} event.Source.Text]
        end

        % Callback function
        function xButtonPushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{"*"} event.Source.Text]
        end

        % Callback function
        function Button_11Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{"+"} event.Source.Text]
        end

        % Callback function
        function Button_19Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{"."} event.Source.Text]
        end

        % Callback function
        function gsgs(app, event)
            
        end

        % Callback function
        function Button_22Pushed(app, event)
            app.TextArea.Value =  [app.TextArea.Value{"^"} event.Source.Text]
        end

        % Button pushed function: CEButton
        function CEButtonPushed(app, event)
            app.EditField.Value = "";
            app.answer.Value = "";
        end

        % Callback function
        function Button_13Pushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                num2str(eval(app.TextArea.Value{1}))]
        end

        % Callback function
        function sinButtonPushed(app, event)
            
        end

        % Callback function
        function tanButtonPushed(app, event)
            
        end

        % Button pushed function: sinButton
        function sinButtonPushed2(app, event)
            hh = app.EditField.Value
            hh = [hh 'sin'];
            app.EditField.Value = hh
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
           cc = app.EditField.Value;
           cc = [cc, '2']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_4
        function Button_4Pushed2(app, event)
          cc = app.EditField.Value;
           cc = [cc, '3']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '4']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_5
        function Button_5Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '5']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_6
        function Button_6Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '6']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_7
        function Button_7Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '7']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_8
        function Button_8Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '8']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '9']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_10
        function Button_10Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '0']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_11
        function Button_11Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '+']
           app.EditField.Value = cc
           app.hide.Value = cc
        end

        % Button pushed function: xButton
        function xButtonPushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '*']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_12
        function Button_12Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '-']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_14
        function Button_14Pushed2(app, event)
            cc = app.EditField.Value;
           cc = [cc, '/']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_19
        function Button_19Pushed2(app, event)
             cc = app.EditField.Value;
           cc = [cc, '.']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_22
        function Button_22Pushed2(app, event)
             cc = app.EditField.Value;
           cc = [cc, '^']
           app.EditField.Value = cc
        end

        % Button pushed function: Button_13
        function Button_13Pushed2(app, event)
     
        cc = app.EditField.Value;
        cc = eval(cc)
        %ff=regexp(cc,'\+','split')'
        %plus = sum(str2double(ff));
        app.answer.Value = num2str(cc);


        end

        % Button pushed function: AnsButton
        function AnsButtonPushed(app, event)
            mm = app.answer.Value;
            app.EditField.Value = mm
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Text = '2';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '4';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed2, true);
            app.Button_5.Text = '5';
            app.Button_11 = uibutton(app.Panel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed2, true);
            app.Button_11.Text = '+';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed2, true);
            app.Button_7.Text = '7';
            app.Button_12 = uibutton(app.Panel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed2, true);
            app.Button_12.Text = '-';
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed2, true);
            app.Button_8.Text = '8';
            app.Button_10 = uibutton(app.Panel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed2, true);
            app.Button_10.Text = '0';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed2, true);
            app.Button_4.Text = '3';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed2, true);
            app.Button_6.Text = '6';
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Text = '9';
            app.ClearButton_2 = uibutton(app.Panel, 'push');
            app.ClearButton_2.Text = 'Clear';
            app.Button_13 = uibutton(app.Panel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed2, true);
            app.Button_13.Text = '=';
            app.Button_14 = uibutton(app.Panel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed2, true);
            app.Button_14.Text = '÷';
            app.sinButton = uibutton(app.Panel, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @sinButtonPushed2, true);
            app.sinButton.Text = 'sin';
            app.tanButton = uibutton(app.Panel, 'push');
            app.tanButton.Text = 'tan';
            app.sqrtButton = uibutton(app.Panel, 'push');
            app.sqrtButton.Text = 'sqrt';
            app.CEButton = uibutton(app.Panel, 'push');
            app.CEButton.ButtonPushedFcn = createCallbackFcn(app, @CEButtonPushed, true);
            app.CEButton.Text = 'CE';
            app.Button_19 = uibutton(app.Panel, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed2, true);
            app.Button_19.Text = '.';
            app.cosButton = uibutton(app.Panel, 'push');
            app.cosButton.Text = 'cos';
            app.AnsButton = uibutton(app.Panel, 'push');
            app.AnsButton.ButtonPushedFcn = createCallbackFcn(app, @AnsButtonPushed, true);
            app.AnsButton.Text = 'Ans';
            app.Button_22 = uibutton(app.Panel, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed2, true);
            app.Button_22.Text = '^';
            app.xButton = uibutton(app.Panel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed2, true);
            app.xButton.Text = 'x';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.answer = uieditfield(app.Panel, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.hide = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calc_App_Designer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end