classdef Challenge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WELCOMEPanel             matlab.ui.container.Panel
        GOODLUCKINYOUREXAMLabel  matlab.ui.control.Label
        timer                    matlab.ui.control.Label
        start                    matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start.Visible = 'Off'
            pause(1.0)
            jj = 11
            for j = 1:jj
                mj = string(jj)
                app.timer.Text = mj
                jj = jj-1
                pause(1.0)
            end
            app.timer.Text = 'GO'
            app.start.Visible = 'On'
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            
            cat = getappdata(0,'valup')
            setappdata(0,'valup',cat)
            delete(app)
            question
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WELCOMEPanel = uipanel(app.UIFigure);
            app.WELCOMEPanel.Title = 'WELCOME';
            app.GOODLUCKINYOUREXAMLabel = uilabel(app.WELCOMEPanel);
            app.GOODLUCKINYOUREXAMLabel.Text = 'GOODLUCK IN YOUR EXAM!';
            app.timer = uilabel(app.WELCOMEPanel);
            app.timer.Text = '';
            app.start = uibutton(app.WELCOMEPanel, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'START';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Challenge

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end