classdef Practice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PRACTICEUIFigure  matlab.ui.Figure
        PRACTICEPanel     matlab.ui.container.Panel
        SUBJECTLabel      matlab.ui.control.Label
        SELECTBYLabel     matlab.ui.control.Label
        select            matlab.ui.control.DropDown
        start             matlab.ui.control.Button
        subject           matlab.ui.control.DropDown
        TOPICLabel        matlab.ui.control.Label
        YEARLabel_2       matlab.ui.control.Label
        SOURCESLabel      matlab.ui.control.Label
        year              matlab.ui.control.DropDown
        source            matlab.ui.control.DropDown
        topic             matlab.ui.control.DropDown
        BackButton        matlab.ui.control.Button
        Button            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             categ = getappdata(0,'classcat')
            global categ
            balu = getappdata(0,'balu')
            categ = getappdata(0,'classcat')
            app.subject.Items = {balu}
        end

        % Value changed function: select
        function selectValueChanged(app, event)
            global categ alldata
            
            value = app.select.Value;
            jj = app.subject.Value
            sub = [jj '.mat']
            path = [pwd,'\' categ '\Subject']

            load ([path '\' sub])
            setappdata(0,'alldata',alldata)
            setappdata(0,'classcat',categ)
            
            if strcmp(value, '')
            app.year.Items = {}
            app.source.Items = {}
            app.topic.Items = {}
            app.topic.Enable = 'Off'
            app.year.Enable = 'Off'
            app.source.Enable = 'Off'
            elseif strcmp(value,'TOPIC')
            app.year.Items = {}
            app.source.Items = {}
            app.topic.Enable = 'On'
            app.year.Enable = 'Off'
            app.source.Enable = 'Off'
            
            bb = unique(string(alldata(2:end,15)))
            bb(1) = []
            bb = rmmissing(bb)
            app.topic.Items = bb
            
            elseif strcmp(value,'ANY')
            app.topic.Items = {}
            app.year.Items = {}
            app.source.Items = {}
            app.topic.Enable = 'Off'
            app.year.Enable = 'Off'
            app.source.Enable = 'Off'
            
            
            elseif strcmp(value,'YEAR')
            app.topic.Items = {}
            app.source.Items = {}
            app.year.Enable = 'On'
            app.source.Enable = 'Off'
            app.topic.Enable = 'Off'
            
            
            bb = unique(string(alldata(2:end,13)))
            bb = rmmissing(bb)
            app.year.Items = bb

            elseif strcmp(value,'SOURCES')
            app.year.Enable = 'Off'
            app.source.Enable = 'On'
            app.topic.Enable = 'Off'
            app.topic.Items = {}
            app.year.Items = {}
            
            bb = unique(string(alldata(2:end,14)))
            bb = rmmissing(bb)
            app.source.Items = bb
            end       
        end

        % Value changed function: subject
        function subjectValueChanged(app, event)
            value = app.subject.Value;
        end

        % Value changed function: topic
        function topicValueChanged(app, event)
            global alldata
            
            value = app.topic.Value
            bb = string(alldata(2:end,15))
            mg = find(value == bb)
            list = []
            for i = 1: length(mg);
                bv = mg(i)
                gg = string(alldata(1:end,13));
                year = gg(bv);
                list = [list,year];

            end
            hd = unique(list)
            b = app.year.Value
            hd = [b hd]
            app.year.Items = hd
            app.year.Items = {}
            
            cc = string(alldata(1:end,1));
            jm = length(mg)
            jk = randperm(jm)
            mm = [];
            
            bg = string(alldata(1:end,2))


            for j = 1:jm
                m = mg(jk(j))
                quest = cc(m);
                
                if ismissing(bg(m)) == 1
                   quest = []
                else
                mm = [mm quest];
                end
            end
            
            app.start.Enable = 'On'
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
         
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
           global alldata
            value = app.select.Value;
           
           
           if strcmp(value,'')
               errordlg('You must select a category','Data Error')
           
           elseif strcmp(value,'ANY')
            bb = unique(string(alldata(2:end,15)));
            bbb = string(alldata(1:end,1))
            bbb = rmmissing(bbb)
            bb = rmmissing(bb)
            
            for i = 1:length(bb)
            kk = find(bbb == bb(i))
            bbb(kk,:) = []
            end
            
           anyq = rmmissing(bbb);
           len = length(anyq)
           setappdata(0,'variables',anyq)
           setappdata(0, 'val',value)
           question


           elseif strcmp(value, 'TOPIC')
               top = app.topic.Value
            bb = string(alldata(2:end,15))
            mg = find(top == bb)
            
            
            cc = string(alldata(2:end,1));
            
            mm = [];
            
            bg = string(alldata(2:end,3))
            
          for j = 1:length(mg)
                m = mg(j)
                quest = cc(m);
                
                if ismissing(bg(m)) == 1
                   quest = []
                else
                mm = [mm quest];
                end
          end
           setappdata(0, 'var',mm)
           setappdata(0, 'val',value)
           question
           
           
            elseif strcmp(value,'YEAR')
            yr = app.year.Value 
            bb = string(alldata(2:end,13))
            mg = find(yr == bb)
            
            
                  cc = string(alldata(2:end,1));
                  mm = [];
                  bg = string(alldata(2:end,3))
            
          for j = 1:length(mg)
                m = mg(j)
                quest = cc(m);
                
                if ismissing(bg(m)) == 1
                   quest = []
                else
                mm = [mm quest];
                end
          end
           setappdata(0, 'var',mm)
          setappdata(0, 'val',value)
           question
           
           
            elseif strcmp(value, 'SOURCES')
            yr = app.source.Value
            bb = string(alldata(2:end,14))
                mg = find(yr == bb)
            
            
                 cc = string(alldata(2:end,1));
                 mm = [];
                 bg = string(alldata(2:end,3))
            
          for j = 1:length(mg)
                m = mg(j)
                quest = cc(m);
                
                if ismissing(bg(m)) == 1
                   quest = []
                else
                mm = [mm quest];
                end
          end
           setappdata(0, 'var',mm)
           setappdata(0, 'val',value)
          
           question
           
            end
            gbu = app.subject.Value
            setappdata(0,'gbu',gbu)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app);
            Test;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global alldata
            value = app.select.Value
            if strcmp(value,'ANY')
            bb = unique(string(alldata(2:end,15)))
            bbb = string(alldata(2:end,1));
            bb(1) = [];
            bb = rmmissing(bb);
            
            for i = 1:length(bb)
            kk = find(bbb == bb(i));
            bbb(kk,:) = [];
            end
            
           anyq = rmmissing(bbb);
           len = length(anyq)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PRACTICEUIFigure = uifigure('Visible', 'off');
            app.PRACTICEUIFigure.Name = 'PRACTICE';
            app.PRACTICEPanel = uipanel(app.PRACTICEUIFigure);
            app.PRACTICEPanel.Title = 'PRACTICE';
            app.SUBJECTLabel = uilabel(app.PRACTICEPanel);
            app.SUBJECTLabel.Text = 'SUBJECT:';
            app.SELECTBYLabel = uilabel(app.PRACTICEPanel);
            app.SELECTBYLabel.Text = 'SELECT BY:';
            app.select = uidropdown(app.PRACTICEPanel);
            app.select.Items = {'', 'ANY', 'TOPIC', 'YEAR', 'SOURCES'};
            app.select.ValueChangedFcn = createCallbackFcn(app, @selectValueChanged, true);
            app.select.Value = '';
            app.start = uibutton(app.PRACTICEPanel, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'START';
            app.subject = uidropdown(app.PRACTICEPanel);
            app.subject.Items = {'Select Subject'};
            app.subject.ValueChangedFcn = createCallbackFcn(app, @subjectValueChanged, true);
            app.subject.Value = 'Select Subject';
            app.TOPICLabel = uilabel(app.PRACTICEPanel);
            app.TOPICLabel.Text = 'TOPIC:';
            app.YEARLabel_2 = uilabel(app.PRACTICEPanel);
            app.YEARLabel_2.Text = 'YEAR:';
            app.SOURCESLabel = uilabel(app.PRACTICEPanel);
            app.SOURCESLabel.Text = 'SOURCES:';
            app.year = uidropdown(app.PRACTICEPanel);
            app.year.Items = {};
            app.year.Value = {};
            app.source = uidropdown(app.PRACTICEPanel);
            app.source.Items = {};
            app.source.Value = {};
            app.topic = uidropdown(app.PRACTICEPanel);
            app.topic.Items = {};
            app.topic.ValueChangedFcn = createCallbackFcn(app, @topicValueChanged, true);
            app.topic.Value = {};
            app.BackButton = uibutton(app.PRACTICEPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Button = uibutton(app.PRACTICEPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.PRACTICEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PRACTICEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PRACTICEUIFigure)
        end
    end
end