classdef Register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        register          matlab.ui.container.Panel
        SURNAMELabel      matlab.ui.control.Label
        OTHERNAMESLabel   matlab.ui.control.Label
        COUNTRYLabel      matlab.ui.control.Label
        STATELabel        matlab.ui.control.Label
        CATEGORYLabel     matlab.ui.control.Label
        LGALabel          matlab.ui.control.Label
        SCHOOLNAMELabel   matlab.ui.control.Label
        CLASSLabel        matlab.ui.control.Label
        DATEOFBIRTHLabel  matlab.ui.control.Label
        GENDERLabel       matlab.ui.control.Label
        RELIGIONLabel     matlab.ui.control.Label
        dd                matlab.ui.control.Label
        mm                matlab.ui.control.Label
        yyyy              matlab.ui.control.Label
        day               matlab.ui.control.DropDown
        month             matlab.ui.control.DropDown
        year              matlab.ui.control.DropDown
        gender            matlab.ui.control.DropDown
        religion          matlab.ui.control.DropDown
        country           matlab.ui.control.DropDown
        state             matlab.ui.control.DropDown
        lga               matlab.ui.control.DropDown
        school            matlab.ui.control.DropDown
        category          matlab.ui.control.DropDown
        class             matlab.ui.control.DropDown
        submit            matlab.ui.control.Button
        surname           matlab.ui.control.EditField
        othername         matlab.ui.control.EditField
        BACKButton        matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global byt
            
            tf = isappdata(0,'reg')
            if tf == 0
                return
            else
            
            
            byt =  getappdata(0,'vart')
            bhj = getappdata(0,'vark')
            if strcmp(bhj,'CHECK')
             if isempty(byt) == 0
                 app.submit.Visible = 'Off'
             sur = string(byt(1))
             odaname = string(byt(2))
           cout = byt(3)
           stat = string(byt(4))
           lg = byt(5)
           skul = byt(6)
           cat = byt(7)
           cla = byt(8)
           gend = byt(9)
           rel = byt(10)
           da = byt(11)
           mont = byt(12)
           yer = byt(13)
           
          app.surname.Value = sur
           app.othername.Value = odaname
           count = app.country.Value
           app.country.Items = string(cout)
           app.state.Items = string(stat)
           app.lga.Items = string(lg)
           app.school.Items = string(skul)
           app.category.Items = string(cat)
           app.class.Items = string(cla)
           app.gender.Items = string(gend)
           app.religion.Items = string(rel)
           app.day.Items = string(da)
           app.month.Items = string(mont)
           app.year.Items = string(yer)
           
           %app.register.Enable = 'Off'
           mkj = [{'surname'},{'othername'},{'country'},{'state'},{'lga'},{'school'},...
               {'category'},{'class'},{'year'},{'month'},{'day'},{'gender'},{'religion'}]
           for k = 1:length(mkj)
               app.(mkj{k}).Enable = 'Off'
           end
    
             end        
             
            elseif strcmp(bhj,'EDIT')
                if isempty(byt) == 0
%                  app.submit.Visible = 'Off'
             sur = string(byt(1))
             odaname = string(byt(2))
           cout = byt(3)
           stat = string(byt(4))
           lg = byt(5)
           skul = byt(6)
           cat = byt(7)
           cla = byt(8)
           gend = byt(9)
           rel = byt(10)
           da = byt(11)
           mont = byt(12)
           yer = byt(13)
           
           country = [{''},{'NIGERIA'},{'ZIMBABWE'}]
            app.country.Items = string(country)
            
            year1 = {''}
            year = string(1990:2025)
            year = [year1,year]
            app.year.Items = string(year)
%             bhu = app.country.Items
%             mko = strmatch(cout,bhu)
%             app.country.Items = bhu(mko)


           app.surname.Value = sur
           app.othername.Value = odaname
                end        
                
            end
            end
           
        end

        % Value changed function: month
        function monthValueChanged(app, event)
            value = app.month.Value;
            
            if strcmp(value, '01') | strcmp(value, '03') | ...
                    strcmp(value, '05') | strcmp(value, '07') | ...
                    strcmp(value, '08') | strcmp(value, '10') | ...
                    strcmp(value, '12')
                day = 1:31
                app.day.Items = string(day)
            elseif strcmp(value, '04') | strcmp(value, '06') | ...
                    strcmp(value, '09') | strcmp(value, '11')
                day = 1:30
                app.day.Items = string(day)
            elseif strcmp(value, '02')
                year = app.year.Value
                if rem(str2double(year),4) ~= 0
                    day = 1:28
                    app.day.Items = string(day)
                else
                    pday = 1:29
                    app.day.Items = string(pday)
                end
            end
        end

        % Value changed function: year
        function yearValueChanged(app, event)
            value = app.year.Value;
            year = app.year.Value
            month = app.month.Value
                if strcmp(month,'02') 
                    if rem(str2double(year),4) ~= 0 
                    day = 1:28
                    app.day.Items = string(day)
                    elseif rem(str2double(year),4) == 0
                    pday = 1:29
                    app.day.Items = string(pday)
                    end
                end
        end

        % Value changed function: country
        function countryValueChanged(app, event)
            
            value = app.country.Value;
            load('lga.mat')
            
            if strcmp(value, 'NIGERIA')
                ab = file(2:39, 2)
                app.state.Items = ab
            end
        end

        % Value changed function: state
        function stateValueChanged(app, event)
            value = app.state.Value
            load('lga.mat')
            states = string(file(1,1:39))
            mgh = find(states == value)
            vg = file(3:46, mgh)
            vgh = rmmissing(vg)
            app.lga.Items = vgh
        end

        % Value changed function: surname
        function surnameValueChanged(app, event)
            value = app.surname.Value;
            country = [{''},{'NIGERIA'},{'ZIMBABWE'}]
            app.country.Items = string(country)
            
            year1 = {''}
            year = string(1990:2025)
            year = [year1,year]
            app.year.Items = string(year)
        end

        % Button pushed function: submit
        function submitPushed(app, event)
            regn=getappdata(0,'reg')
            
            if isempty(regn) == 0
            
            surn = app.surname.Value
            othern = app.othername.Value
            countr = app.country.Value
            sta = app.state.Value
            lg = app.lga.Value
            scho = app.school.Value
            cate = app.category.Value
            cla = app.class.Value
            ye = app.year.Value
            mon = app.month.Value
            dy = app.day.Value
            dob = [dy '/' mon '/' ye]
            gend = app.gender.Value
            relig = app.religion.Value
            variables = [{surn},{othern},{countr},{sta},...
                {lg},{scho},{cate},{cla},{dob},{gend},{relig}]
            % column = {'Surname','Othername',
            setappdata(0, 'val',variables)
            app.UIFigure.Visible = 'Off'
            setappdata(0, 'fig', app.UIFigure)
            
            Spelling
            
            else
                load studentdata.mat
                ghty = string(gg(:,13))
                hjm =find(ghty == regn)
                
            surn = app.surname.Value
            othern = app.othername.Value
            countr = app.country.Value
            sta = app.state.Value
            lg = app.lga.Value
            scho = app.school.Value
            cate = app.category.Value
            cla = app.class.Value
            ye = app.year.Value
            mon = app.month.Value
            dy = app.day.Value
            dob = [dy '/' mon '/' ye]
            gend = app.gender.Value
            relig = app.religion.Value
            
            gg{hjm,1} = surn
            gg{hjm,2} = othern
            gg{hjm,3} = countr
            gg{hjm,4} = sta
            gg{hjm,5} = lg
            gg{hjm,6} = scho
            gg{hjm,7} = cate
            gg{hjm,8} = cla
            gg{hjm,9} = dob
            gg{hjm,10} = gend
            gg{hjm,11} = relig
            save studentdata.mat gg
            
            load([pwd '\' cate '\' 'studentdata.mat'])
            
            
            upd = gg(hjm,1:11)
            if strcmp(cate, 'PRIMARY')
%                 load([pwd '\' cate '\' 'studentdata.mat'])
                  vku = string(pgg(:,13))
                  kl = find(vku == regn)
                  pgg(kl,1:11) = upd
                  save([pwd '\' cate '\' 'studentdata.mat'], 'pgg')
            elseif strcmp(cate,'JSS')
%                 load([pwd '\' cate '\' 'studentdata.mat'])
                 vku = string(jgg(:,13))
                 kl = find(vku == regn)
                 pgg(kl,1:11) = upd
                 save([pwd '\' cate '\' 'studentdata.mat'], 'jgg')
            elseif strcmp(cate, 'SSS')
%                 load([pwd '\' cate '\' 'studentdata.mat'])
                 vku = string(sgg(:,13))
                 kl = find(vku == regn)
                 pgg(kl,1:11) = upd
                 save([pwd '\' cate '\' 'studentdata.mat'], 'sgg')
            end
            
            
            delete(app)
            msgbox('Data updated succesfully!')
%             rmappdata(0,'reg')
%             rmappdata(0,'vark')
            end
        end

        % Size changed function: register
        function registerSizeChanged(app, event)
            position = app.register.Position;
            
        end

        % Value changed function: othername
        function othernameValueChanged(app, event)
            value = app.othername.Value;
            
        end

        % Value changed function: category
        function categoryValueChanged(app, event)
            value = app.category.Value;
            if strcmp(value, 'PRIMARY')
                list = [{' '}, {'1'},{'2'},{'3'},...
                    {'4'},{'5'},{'6'}]
                app.class.Items = list
            elseif strcmp(value, 'JSS')
                list = [{' '}, {'1'},{'2'},{'3'}]
                app.class.Items = list
            elseif strcmp(value, 'SSS')
                list = [{' '}, {'1'},{'2'},{'3'}]
                app.class.Items = list
            end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            global byt
            delete(app)
            % rmappdata(0,'vart')
            if isempty(byt)==0
                mydetails
            else
            Dashboard
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global byt
            if isempty(byt)==0
            rmappdata(0,'vart')
            delete(app)
            else
            delete(app)
            end
        end

        % Value changed function: lga
        function lgaValueChanged(app, event)
            value = app.lga.Value;
            
        end

        % Value changed function: class
        function classValueChanged(app, event)
            value = app.class.Value;
            
        end

        % Value changed function: school
        function schoolValueChanged(app, event)
            value = app.school.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.register = uipanel(app.UIFigure);
            app.register.Title = 'REGISTER';
            app.register.SizeChangedFcn = createCallbackFcn(app, @registerSizeChanged, true);
            app.SURNAMELabel = uilabel(app.register);
            app.SURNAMELabel.Text = 'SURNAME:';
            app.OTHERNAMESLabel = uilabel(app.register);
            app.OTHERNAMESLabel.Text = 'OTHER NAMES:';
            app.COUNTRYLabel = uilabel(app.register);
            app.COUNTRYLabel.Text = 'COUNTRY:';
            app.STATELabel = uilabel(app.register);
            app.STATELabel.Text = 'STATE:';
            app.CATEGORYLabel = uilabel(app.register);
            app.CATEGORYLabel.Text = 'CATEGORY:';
            app.LGALabel = uilabel(app.register);
            app.LGALabel.Text = 'L.G.A.:';
            app.SCHOOLNAMELabel = uilabel(app.register);
            app.SCHOOLNAMELabel.Text = 'SCHOOL NAME:';
            app.CLASSLabel = uilabel(app.register);
            app.CLASSLabel.Text = 'CLASS:';
            app.DATEOFBIRTHLabel = uilabel(app.register);
            app.DATEOFBIRTHLabel.Text = 'DATE OF BIRTH:';
            app.GENDERLabel = uilabel(app.register);
            app.GENDERLabel.Text = 'GENDER:';
            app.RELIGIONLabel = uilabel(app.register);
            app.RELIGIONLabel.Text = 'RELIGION:';
            app.dd = uilabel(app.register);
            app.dd.Text = 'DAY';
            app.mm = uilabel(app.register);
            app.mm.Text = 'MONTH';
            app.yyyy = uilabel(app.register);
            app.yyyy.Text = 'YEAR';
            app.day = uidropdown(app.register);
            app.day.Items = {''};
            app.day.Value = '';
            app.month = uidropdown(app.register);
            app.month.Items = {'', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12'};
            app.month.ValueChangedFcn = createCallbackFcn(app, @monthValueChanged, true);
            app.month.Value = '';
            app.year = uidropdown(app.register);
            app.year.Items = {};
            app.year.ValueChangedFcn = createCallbackFcn(app, @yearValueChanged, true);
            app.year.Value = {};
            app.gender = uidropdown(app.register);
            app.gender.Items = {'', 'MALE', 'FEMALE'};
            app.gender.Value = '';
            app.religion = uidropdown(app.register);
            app.religion.Items = {'', 'CHRISTIANITY', 'ISLAM', 'TRADITIONAL'};
            app.religion.Value = '';
            app.country = uidropdown(app.register);
            app.country.Items = {'Select Country'};
            app.country.ValueChangedFcn = createCallbackFcn(app, @countryValueChanged, true);
            app.country.Value = 'Select Country';
            app.state = uidropdown(app.register);
            app.state.Items = {''};
            app.state.ValueChangedFcn = createCallbackFcn(app, @stateValueChanged, true);
            app.state.Value = '';
            app.lga = uidropdown(app.register);
            app.lga.Items = {''};
            app.lga.ValueChangedFcn = createCallbackFcn(app, @lgaValueChanged, true);
            app.lga.Value = '';
            app.school = uidropdown(app.register);
            app.school.Items = {''};
            app.school.ValueChangedFcn = createCallbackFcn(app, @schoolValueChanged, true);
            app.school.Value = '';
            app.category = uidropdown(app.register);
            app.category.Items = {'', 'PRIMARY', 'JSS', 'SSS'};
            app.category.ValueChangedFcn = createCallbackFcn(app, @categoryValueChanged, true);
            app.category.Value = '';
            app.class = uidropdown(app.register);
            app.class.Items = {''};
            app.class.ValueChangedFcn = createCallbackFcn(app, @classValueChanged, true);
            app.class.Value = '';
            app.submit = uibutton(app.register, 'push');
            app.submit.ButtonPushedFcn = createCallbackFcn(app, @submitPushed, true);
            app.submit.Text = 'SUBMIT';
            app.surname = uieditfield(app.register, 'text');
            app.surname.ValueChangedFcn = createCallbackFcn(app, @surnameValueChanged, true);
            app.othername = uieditfield(app.register, 'text');
            app.othername.ValueChangedFcn = createCallbackFcn(app, @othernameValueChanged, true);
            app.BACKButton = uibutton(app.register, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Register

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end