classdef Spelling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CONFIRMYOURCREDENTIALSPanel  matlab.ui.container.Panel
        EDITButton                   matlab.ui.control.Button
        CONTINUEButton               matlab.ui.control.Button
        CheckthespellingsandmakesuretheyarecorrectLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: EDITButton
        function EDITButtonPushed(app, event)
            global jj
            jj = getappdata(0,'fig')
            jj.Visible = 'On'
            % close(Spelling)
            delete(app)
        end

        % Button pushed function: CONTINUEButton
        function CONTINUEButtonPushed(app, event)
           load('studentdata.mat')
           
           
            global jj
            ml = getappdata(0, 'val')
            surname = ml(1)
            othername = ml(2)
            country = ml(3)
            state = ml(4)
            lga = ml(5)
            school = ml(6)
            category = ml(7)
            class = ml(8)
            dob = ml(9)
            gender = ml(10)
            religion = ml(11)
            variables = {'SURNAME','OTHERNAME','COUNTRY','STATE',...
                'LGA','SCHOOL','CATEGORY','CLASS','DOB',...
                'GENDER','RELIGION'}
            data = [surname,othername,country,...
                state,lga,school,category,...
                class,dob,gender,religion]
            
%             msgbox('Data Saved')
%             pause(1)
            kkl = randperm(9,4)
            code = []
            for i = 1:length(kkl)
                k = string(kkl(i))
                code = strcat(code, k)
            end
            load('lga.mat')
           hjh = string(file(1,1:39))
            
            mku = find(hjh == state)
            hjm = file{2,mku}
            
            strcmp(category, 'PRIMARY')
            nj = '1'
            strcmp(category, 'JSS')
            nj = '2'
            strcmp(category, 'SSS')
            nj = 3
            
            
            db = date
            % db = str2mat(dob)
            year = db(length(db)-1:end)
            year = string(year)
            hjm = string(hjm)
            nj = string(nj)
            code = string(code)
            cent = strcat(hjm, nj, year, code)
            setappdata(0,'vgbh',cent)


            delete(app)
            codegenerator
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CONFIRMYOURCREDENTIALSPanel = uipanel(app.UIFigure);
            app.CONFIRMYOURCREDENTIALSPanel.Title = 'CONFIRM YOUR CREDENTIALS';
            app.EDITButton = uibutton(app.CONFIRMYOURCREDENTIALSPanel, 'push');
            app.EDITButton.ButtonPushedFcn = createCallbackFcn(app, @EDITButtonPushed, true);
            app.EDITButton.Text = 'EDIT';
            app.CONTINUEButton = uibutton(app.CONFIRMYOURCREDENTIALSPanel, 'push');
            app.CONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @CONTINUEButtonPushed, true);
            app.CONTINUEButton.Text = 'CONTINUE';
            app.CheckthespellingsandmakesuretheyarecorrectLabel = uilabel(app.CONFIRMYOURCREDENTIALSPanel);
            app.CheckthespellingsandmakesuretheyarecorrectLabel.Text = 'Check the spellings and make sure they are correct';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spelling

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end