classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TESTPanel     matlab.ui.container.Panel
        practice      matlab.ui.control.StateButton
        challenge     matlab.ui.control.StateButton
        ed            matlab.ui.control.EditField
        guage         matlab.ui.control.LinearGauge
        Label         matlab.ui.control.Label
        labelpc       matlab.ui.control.Label
        SUBJECTLabel  matlab.ui.control.Label
        subject       matlab.ui.control.DropDown
        BACKButton    matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global classcat regn
            
            regn = getappdata(0,'reg')
            setappdata(0,'regn', regn)
            app.ed.Visible = 'Off'
            
            classcat = getappdata(0,'classcat')
            setappdata(0,'classcat',classcat)


          try
            dr = [pwd,'\' classcat '\Subject']
            jj = dir(dr)
            gg = []
            for i = 1:length(jj)
                bg = string(jj(i).name)
                kg = regexp(bg,'\.', 'split')
                jui = strcmp(kg(2),'mat')
                if jui == 1
                    gg = [gg kg(1)]
%                 else
%                     continue
                end
               
            end
            gg = [{''} gg]
            app.subject.Items = gg
          catch
              msgbox('No subject available','Contact Admin','help')
          end
        end

        % Value changed function: practice
        function practiceValueChanged(app, event)
            balue = app.subject.Value
            if isempty(balue)
                msgbox('Pick a Subject')
            else
            setappdata(0,'balu',balue)
            
            value = app.practice.Text;
            app.ed.Value = value
            gpm = app.ed.Value
            setappdata(0,'Practice', gpm)
            
            delete(app)
            Practice
            end
        end

        % Value changed function: challenge
        function challengeValueChanged(app, event)
            
            balue = app.subject.Value
            if isempty(balue)
                msgbox('Pick a subject')
            else
            setappdata(0,'balu',balue)
            
            value = app.challenge.Text;
            app.ed.Value = value
            gpm = app.ed.Value
            setappdata(0,'Practice',gpm)
            delete(app)
            validation
            end
        end

        % Value changed function: subject
        function subjectValueChanged(app, event)
            global classcat regn
            
            value = app.subject.Value;
            
            if isempty(value) == 0
            value = [value 'Result']
            sub = [value,'.mat']
            directory = [pwd '\' classcat '\']
            result = {}
            
           
            vb = dir([pwd '\' classcat])
            list = []
            for i = 1: length(vb)
                jko = vb(i).name
                list = [list {jko}]
            end
            list = string(list)
            
            subj = find(list == sub)
            
            
            if isempty(subj) == 1
                
            if strcmp(classcat, 'PRIMARY')
                
                save([directory,sub], 'result')
                load([pwd '\' classcat '\' 'studentdata.mat'])
                load([pwd '\' classcat '\' sub])
                
                pg = pgg(1:end, 1:2)
                result = pg
                
                gp = pgg(1:end,13)
                result(1:end,3) = gp
                
               table = [{'PRACTICE 1'},{'PRACTICE 2'},{'PRACTICE 3'},{'PRACTICE 4'},{'PRACTICE 5'},{'CHALLENGE'}]
               result(1,4:9) = table
               
               save([directory, sub], 'result')
            elseif strcmp(classcat,'JSS')
                
                save([directory,sub], 'result')
                load([pwd '\' classcat '\' 'studentdata.mat'])
                load([pwd '\' classcat '\' sub])
                
                jg = jgg(1:end, 1:2)
                result = jg
                
                gj = jgg(1:end,13)
                result(1:end,3) = gj
                
                table = [{'PRACTICE 1'},{'PRACTICE 2'},{'PRACTICE 3'},{'PRACTICE 4'},{'PRACTICE 5'},{'CHALLENGE'}]
                result(1,4:9) = table
                
                save([directory, sub], 'result')
            elseif strcmp(classcat, 'SSS')
                
                save([directory,sub], 'result')
                load([pwd '\' classcat '\' 'studentdata.mat'])
                load([pwd '\' classcat '\' sub])
                
                sg = sgg(1:end, 1:2)
                result = sg
                
                gs = sgg(1:end,13)
                result(1:end,3) = gs
                
                table = [{'PRACTICE 1'},{'PRACTICE 2'},{'PRACTICE 3'},{'PRACTICE 4'},{'PRACTICE 5'},{'CHALLENGE'}]
                result(1,4:9) = table
                
                save([directory, sub], 'result')
            end
            end


            load([pwd '\' classcat '\' sub])
            app.challenge.Enable = 'Off'
            mjk = string(result(:,3))
            vj = find(mjk == regn)
            jun1 = result{vj,4}
            jun2 = result{vj,5}
            jun3 = result{vj,6}
            jun4 = result{vj,7}
            jun5 = result{vj,8}
            chl = result{vj,9}


            if isempty(jun1) == 1
                app.guage.Value = 1
                
                guage = app.guage.Value
            setappdata(0,'gage',guage)
            elseif isempty(jun1)==0 && isempty(jun2)== 1
                app.guage.Value = 2
                
                guage = app.guage.Value
            setappdata(0,'gage',guage)
            elseif isempty(jun1)==0 && isempty(jun2)== 0 && isempty(jun3)== 1
                app.guage.Value = 3
                
                guage = app.guage.Value
            setappdata(0,'gage',guage)
            elseif isempty(jun1)==0 && isempty(jun2)== 0 &&...
                    isempty(jun3)== 0 && isempty(jun4)== 1
                app.guage.Value = 4
                
                    guage = app.guage.Value
            setappdata(0,'gage',guage)
                
            elseif isempty(jun1)==0 && isempty(jun2)== 0 &&...
                    isempty(jun3)== 0 && isempty(jun4)== 0 && isempty(jun5)== 1
                app.guage.Value = 5
                
                    guage = app.guage.Value
                setappdata(0,'gage',guage)
                
            elseif isempty(jun1)==0 && isempty(jun2)== 0 && isempty(jun3)== 0 ...
                    && isempty(jun4)== 0 && isempty(jun5)== 0  && isempty(chl) == 1
%                 app.guage.Value = 1
%                 app.guage.Limits = [1 10]
%                 app.guage.MajorTicks = [1,2,3,4,5,6,7,8,9,10]
%                 app.guage.MajorTickLabels = {'1','2','3','4','5','6','7','8','9','10'}
%                 app.labelpc.Text = 'Challenge Level'
                app.guage.Visible = 'Off'
                app.practice.Enable = 'Off'
                app.challenge.Enable = 'On'
                msgbox('Now you have to take the challenge','NOTICE!','help')
                
                guage = app.guage.Value
                setappdata(0,'gage',guage)
                
             
              elseif isempty(jun1)==0 && isempty(jun2)== 0 &&...
                    isempty(jun3)== 0 && isempty(jun4)== 0 && isempty(jun5)== 0 ...
                    && isempty(chl) == 0 
                
                    
                    app.guage.Visible = 'On'
                    app.practice.Enable = 'On'
                    app.challenge.Enable = 'Off'
                    
            end
            else
                return
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            delete(app);
            detailspage;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TESTPanel = uipanel(app.UIFigure);
            app.TESTPanel.Title = 'TEST';
            app.practice = uibutton(app.TESTPanel, 'state');
            app.practice.ValueChangedFcn = createCallbackFcn(app, @practiceValueChanged, true);
            app.practice.Text = 'PRACTICE';
            app.challenge = uibutton(app.TESTPanel, 'state');
            app.challenge.ValueChangedFcn = createCallbackFcn(app, @challengeValueChanged, true);
            app.challenge.Text = 'CHALLENGE';
            app.ed = uieditfield(app.TESTPanel, 'text');
            app.ed.Enable = 'off';
            app.ed.Visible = 'off';
            app.guage = uigauge(app.TESTPanel, 'linear');
            app.guage.Limits = [1 5];
            app.guage.MajorTicks = [1 2 3 4 5];
            app.guage.Value = 1;
            app.Label = uilabel(app.TESTPanel);
            app.Label.Text = 'Note that you must complete five practices to be eligible for challenge!';
            app.labelpc = uilabel(app.TESTPanel);
            app.labelpc.Text = ' Practice Level';
            app.SUBJECTLabel = uilabel(app.TESTPanel);
            app.SUBJECTLabel.Text = 'SUBJECT:';
            app.subject = uidropdown(app.TESTPanel);
            app.subject.Items = {'Select Subject'};
            app.subject.ValueChangedFcn = createCallbackFcn(app, @subjectValueChanged, true);
            app.subject.Value = 'Select Subject';
            app.BACKButton = uibutton(app.TESTPanel, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end