classdef admin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        panel                        matlab.ui.container.Panel
        get_started                  matlab.ui.control.Button
        table                        matlab.ui.control.Table
        Label                        matlab.ui.control.Label
        light                        matlab.ui.control.RockerSwitch
        question                     matlab.ui.control.Button
        question_2                   matlab.ui.control.Button
        edit                         matlab.ui.control.NumericEditField
        OKButton                     matlab.ui.control.Button
        question_3                   matlab.ui.control.Button
        PressOKtoeffectchangesLabel  matlab.ui.control.Label
        category                     matlab.ui.control.DropDown
        Tab2                         matlab.ui.container.Tab
        MALPRACTICECHECKPanel        matlab.ui.container.Panel
        CHOOSEPUNISHMENTLabel        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% %            try
% %             appl = getappdata(0,'lav')
% %             lam = getappdata(0,'la')
% %             jm = str2double(appl)
% %             app.edit.Value = jm
% %            catch
% %            end
%             if strcmp(lam,'')
            kk = [{'ANY'},{'TOPIC'},{'YEAR'},{'SOURCES'}]
            app.category.Items = kk
%             elseif strcmp(lam,'ANY')
%                 kk = [{lam},{'TOPIC'},{'YEAR'},{'SOURCES'}]
%                 app.category.Items = kk
%             elseif strcmp(lam,'TOPIC')
%                 kk = [{lam},{'ANY'},{'YEAR'},{'SOURCES'}]
%                 app.category.Items = kk
%             elseif strcmp(lam,'YEAR')
%                 kk = [{lam}, {'ANY'},{'TOPIC'},{'SOURCES'}]
%                 app.category.Items = kk
%             elseif strcmp(lam,'SOURCES')
%                 kk = [{lam}, {'ANY'},{'TOPIC'},{'YEAR'}]
%             app.category.Items = kk
% 
%             end
        end

        % Callback function
        function get_startedButtonPushed(app, event)
            prompt = {'How many students are available:'}
            definput = 1
            title = 'Admin Page'
            jj = inputdlg(prompt,title,definput)
            i = 0
           while i< str2double(jj)
               app2
               i=i+1
           end
        end

        % Callback function
        function lightValueChanged(app, event)
            value = app.light.Value;
            switch value
                case 'On'
                    app.panel.BackgroundColor = [1 1 1]
                case 'Off'
                    app.panel.BackgroundColor = [0.65 0.65 0.65]
                    
            end
        end

        % Callback function
        function questionButtonPushed(app, event)
            global sub
%             try
            answer = questdlg('Select Class Level','Class','PRIMARY','JSS','SSS','PRIMARY')    
                
            [filename, pathname] = uigetfile({'*.xlsx';'*.doc';'*.csv'},'Select file','xlsx')
            h = waitbar(0, "Please Wait...");
            r_quest = regexp(filename,'\.|\?','split');
            jj = r_quest{1}
            [data, text, alldata] = xlsread([pathname filename],'QUESTION');

            sub = [jj,'.mat']
            directory = [pwd '\' answer '\Subject\']
            save([directory,sub], 'alldata');
            dd = waitbar(tic/toc,h)
            close(dd)
            msgbox('Question Loaded Successfully')
%             catch
%             end
        end

        % Callback function
        function editValueChanged(app, event)
%             global sub pathname
% %             try
%             load ([pathname sub]) 
%             all = length(alldata)
%             bgg = app.edit.Value
%             if bgg > all
%                 msgbox("Number of Questions not enough")
%             end
% %             catch
% %                 msgbox('You need to load a subject')
% %             end
        end

        % Callback function
        function OKButtonPushed(app, event)
            cat = app.category.Value
            nm = app.edit.Value
            jj = rem(nm,2)
            if strcmp(cat,'') 
                errordlg('Category cannot be left empty!', 'Type Error')
            elseif jj ~= 0
                errordlg('Number of questions must be even', 'Error')
            else
           
            setappdata(0, 'noquest',nm)
            setappdata(0,'valup',cat)
            msgbox('Change Successful')
            app = string(nm)
            setappdata(0,'lav',app)
            setappdata(0,'la',cat)
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.panel = uipanel(app.Tab);
            app.panel.Title = 'CBT_App_Admin_View';
            app.get_started = uibutton(app.panel, 'push');
            app.get_started.Text = 'Getting Started';
            app.table = uitable(app.panel);
            app.table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table.RowName = {};
            app.Label = uilabel(app.panel);
            app.Label.Text = '';
            app.light = uiswitch(app.panel, 'rocker');
            app.light.Items = {'Night Mode', 'DayLight Mode'};
            app.light.Value = 'Night Mode';
            app.question = uibutton(app.panel, 'push');
            app.question.Text = 'Question Set-up';
            app.question_2 = uibutton(app.panel, 'push');
            app.question_2.Text = 'Number of Questions:';
            app.edit = uieditfield(app.panel, 'numeric');
            app.edit.Limits = [30 50];
            app.edit.Value = 50;
            app.OKButton = uibutton(app.panel, 'push');
            app.OKButton.Text = 'OK';
            app.question_3 = uibutton(app.panel, 'push');
            app.question_3.Text = 'Category';
            app.PressOKtoeffectchangesLabel = uilabel(app.panel);
            app.PressOKtoeffectchangesLabel.Text = 'Press ''OK'' to effect changes';
            app.category = uidropdown(app.panel);
            app.category.Items = {};
            app.category.Value = {};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.MALPRACTICECHECKPanel = uipanel(app.Tab2);
            app.MALPRACTICECHECKPanel.Title = 'MALPRACTICE CHECK';
            app.CHOOSEPUNISHMENTLabel = uilabel(app.MALPRACTICECHECKPanel);
            app.CHOOSEPUNISHMENTLabel.Text = 'CHOOSE PUNISHMENT:';
            app.UIAxes = uiaxes(app.MALPRACTICECHECKPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = admin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end