classdef codegenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        REGISTERATIONNUMBERLabel  matlab.ui.control.Label
        CREATEPASSWORDLabel       matlab.ui.control.Label
        CONFIRMPASSWORDLabel      matlab.ui.control.Label
        registrationcode          matlab.ui.control.Label
        createp                   matlab.ui.control.EditField
        confirmp                  matlab.ui.control.EditField
        OKButton                  matlab.ui.control.Button
        epass                     matlab.ui.control.EditField
        table                     matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global mql
            mql = getappdata(0, 'vgbh')
            app.registrationcode.Text = mql
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global mql
            mql = str2mat(mql)
            cr = app.createp.Value
            con = app.confirmp.Value
            if strcmp(cr, con)
                dll = getappdata(0, 'val')
                surname = dll(1)
            othername = dll(2)
            country = dll(3)
            state = dll(4)
            lga = dll(5)
            school = dll(6)
            category = dll(7)
            clss = dll(8)
            dob = dll(9)
            gender = dll(10)
            religion = dll(11)
            cate = cell2mat(category)   
            data = [surname,othername,country,state,...
                lga,school,category,clss,dob,gender,religion,...
                con,mql,{''},{''},{''},{''},{''},{''}]
            load('studentdata.mat')
            
            app.table.Data = data
            gg = [gg;data]
            save studentdata.mat gg
            % app.table.ColumnName = variables
            
            load studentdata.mat gg
            fg = string(gg(:,7))
            vic = find(fg == cate)
            fl = gg(1,:)
            for i = 1: length(vic)
                vl = gg(vic(i),:)
                fl = [fl;vl]
            end
            
            if strcmp(cate, 'PRIMARY')
%                 load([pwd '\' cate '\' 'studentdata.mat'])
                pgg = fl
                save([pwd '\' cate '\' 'studentdata.mat'], 'pgg')
            elseif strcmp(cate,'JSS')
%                 load([pwd '\' cate '\' 'studentdata.mat'])
                jgg = fl
                save([pwd '\' cate '\' 'studentdata.mat'], 'jgg')
            elseif strcmp(cate, 'SSS')
%                 load([pwd '\' cate '\' 'studentdata.mat'])
                sgg = fl
                save([pwd '\' cate '\' 'studentdata.mat'], 'sgg')
            end
            
            
            name = [cell2mat(surname),' ',cell2mat(othername)]
             setappdata(0,'name',name)
             setappdata(0,'regn',mql)
             delete(app)
                takepicture
                
            else
                errordlg('Passwords do not match','PASSWORD ERROR!')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.REGISTERATIONNUMBERLabel = uilabel(app.Panel);
            app.REGISTERATIONNUMBERLabel.Text = 'REGISTERATION NUMBER:';
            app.CREATEPASSWORDLabel = uilabel(app.Panel);
            app.CREATEPASSWORDLabel.Text = 'CREATE PASSWORD:';
            app.CONFIRMPASSWORDLabel = uilabel(app.Panel);
            app.CONFIRMPASSWORDLabel.Text = 'CONFIRM PASSWORD:';
            app.registrationcode = uilabel(app.Panel);
            app.createp = uieditfield(app.Panel, 'text');
            app.confirmp = uieditfield(app.Panel, 'text');
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.epass = uieditfield(app.Panel, 'text');
            app.epass.Visible = 'off';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table.RowName = {};
            app.table.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = codegenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end