classdef congratulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CONGRATULATIONSPanel           matlab.ui.container.Panel
        axes                           matlab.ui.control.UIAxes
        namelabel                      matlab.ui.control.Label
        YOURREGISTRATIONNUMBERISLabel  matlab.ui.control.Label
        ok                             matlab.ui.control.Button
        reglabel                       matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reg = getappdata(0,'regn')
            name = getappdata(0,'name')
            app.reglabel.Text = reg
            app.namelabel.Text = name

            
           cd = dir('Profile_Pictures')
           ab = []
            for i = 1:length(cd)
                gb = cd(i).name
                ab = [ab {gb}]
            end
            ab = string(ab)
            
%             vq = regexp(reg,'/','split')
%             namep = strcat(vq(1),'_',vq(2),'_',vq(3))
            reg = string(reg)
            namep = str2mat(reg)
            image = imread([pwd '\Profile_Pictures' '\' [namep '.jpg']])
            imshow(image,'Parent',app.axes)
        end

        % Button pushed function: ok
        function okButtonPushed(app, event)
            rmappdata(0,'regn')
            delete(app)
            Dashboard
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CONGRATULATIONSPanel = uipanel(app.UIFigure);
            app.CONGRATULATIONSPanel.Title = 'CONGRATULATIONS!';
            app.axes = uiaxes(app.CONGRATULATIONSPanel);
            title(app.axes, 'Profile Picture')
            app.axes.XTick = [];
            app.axes.XTickLabel = {'[ ]'};
            app.axes.YTick = [];
            app.axes.YTickLabel = {'[ ]'};
            app.namelabel = uilabel(app.CONGRATULATIONSPanel);
            app.YOURREGISTRATIONNUMBERISLabel = uilabel(app.CONGRATULATIONSPanel);
            app.YOURREGISTRATIONNUMBERISLabel.Text = 'YOUR REGISTRATION NUMBER IS:';
            app.ok = uibutton(app.CONGRATULATIONSPanel, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.ok.Text = 'OK';
            app.reglabel = uilabel(app.CONGRATULATIONSPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = congratulations

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end