classdef detailspage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        MYDETAILSButton  matlab.ui.control.Button
        MYSCOREButton    matlab.ui.control.Button
        MYTESTButton     matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: MYTESTButton
        function MYTESTButtonPushed(app, event)
            delete(app)
            Test
        end

        % Button pushed function: MYSCOREButton
        function MYSCOREButtonPushed(app, event)
            delete(app)
            myscore
        end

        % Button pushed function: MYDETAILSButton
        function MYDETAILSButtonPushed(app, event)
           regn=getappdata(0,'reg')
            load('studentdata.mat')
           mjhy = string(gg(:,13))
           hj = find(mjhy == regn)
           sur = gg{hj,1}
           odaname = gg{hj,2}
           count = gg{hj,3}
           stat = gg{hj,4}
           lg = gg{hj,5}
           skul = gg{hj,6}
           cat = gg{hj,7}
           cla = gg{hj,8}
           gend = gg{hj,10}
           rel = gg{hj,11}
           dob = gg{hj,9}
           da = dob(1:2)
           mont = dob(4:5)
           yer = dob(7:10)
           
           vart = [{sur} {odaname} {count} {stat} {lg} {skul} {cat}...
               {cla} {gend} {rel} {da} {mont} {yer}]
           setappdata(0,'vart',vart)
           delete(app)
            
            delete(app)
            
            mydetails
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MYDETAILSButton = uibutton(app.UIFigure, 'push');
            app.MYDETAILSButton.ButtonPushedFcn = createCallbackFcn(app, @MYDETAILSButtonPushed, true);
            app.MYDETAILSButton.Text = 'MY DETAILS';
            app.MYSCOREButton = uibutton(app.UIFigure, 'push');
            app.MYSCOREButton.ButtonPushedFcn = createCallbackFcn(app, @MYSCOREButtonPushed, true);
            app.MYSCOREButton.Text = 'MY SCORE';
            app.MYTESTButton = uibutton(app.UIFigure, 'push');
            app.MYTESTButton.ButtonPushedFcn = createCallbackFcn(app, @MYTESTButtonPushed, true);
            app.MYTESTButton.Text = 'MY TEST';
        end
    end

    methods (Access = public)

        % Construct app
        function app = detailspage

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end