classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        LOGINLabel               matlab.ui.control.Label
        REGISTRATIONNUMBERLabel  matlab.ui.control.Label
        PASSWORDLabel            matlab.ui.control.Label
        password                 matlab.ui.control.EditField
        regno                    matlab.ui.control.EditField
        back                     matlab.ui.control.Button
        back_2                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: back
        function backButtonPushed(app, event)
            delete(app)
            Dashboard
        end

        % Button pushed function: back_2
        function back_2ButtonPushed(app, event)
            reg = app.regno.Value
            pass = app.password.Value
            load('studentdata.mat')
            ggt = string(gg(1:end,13))
            muj = find(ggt == reg)
            if isempty(muj) == 1
                msgbox('Inavlid Registration Number')
            else
            htr = gg{muj,12}
            if strcmp(htr,pass)
           delete(app)
           setappdata(0,'reg',reg)
           
                loginwelcome
            else
                errordlg('Check your inputs','Error')
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LOGINLabel = uilabel(app.Panel);
            app.LOGINLabel.Text = 'LOG IN:';
            app.REGISTRATIONNUMBERLabel = uilabel(app.Panel);
            app.REGISTRATIONNUMBERLabel.Text = 'REGISTRATION NUMBER:';
            app.PASSWORDLabel = uilabel(app.Panel);
            app.PASSWORDLabel.Text = 'PASSWORD:';
            app.password = uieditfield(app.Panel, 'text');
            app.regno = uieditfield(app.Panel, 'text');
            app.back = uibutton(app.Panel, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Text = 'BACK';
            app.back_2 = uibutton(app.Panel, 'push');
            app.back_2.ButtonPushedFcn = createCallbackFcn(app, @back_2ButtonPushed, true);
            app.back_2.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end