classdef loginwelcome < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WELCOMEUIFigure  matlab.ui.Figure
        WELCOMEPanel     matlab.ui.container.Panel
        axes             matlab.ui.control.UIAxes
        lname            matlab.ui.control.Label
        lreg             matlab.ui.control.Label
        lclass           matlab.ui.control.Label
        OKButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reg = getappdata(0,'reg')
            app.lreg.Text = reg
            load studentdata.mat
            hjk = string(gg(:,13))
            mjt = find(hjk == reg)
            name1 = gg{mjt,1}
            name2 = gg{mjt,2}
            class1 = gg{mjt,7}
            class2 = gg{mjt,8}
            name = [name1 ' ' name2]
            class = [class1 class2]
            app.lname.Text = name
            app.lclass.Text = class
            
            reg = string(reg)
            namep = str2mat(reg)
            image = imread([pwd '\Profile_Pictures' '\' [namep '.jpg']])
            imshow(image,'Parent',app.axes)
            setappdata(0,'classcat',class1)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app)
            detailspage
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WELCOMEUIFigure = uifigure;
            app.WELCOMEUIFigure.Name = 'WELCOME';
            app.WELCOMEPanel = uipanel(app.WELCOMEUIFigure);
            app.WELCOMEPanel.Title = 'WELCOME';
            app.axes = uiaxes(app.WELCOMEPanel);
            app.axes.XTick = [];
            app.axes.XTickLabel = '';
            app.axes.YTick = [];
            app.axes.YTickLabel = '';
            app.lname = uilabel(app.WELCOMEPanel);
            app.lreg = uilabel(app.WELCOMEPanel);
            app.lclass = uilabel(app.WELCOMEPanel);
            app.OKButton = uibutton(app.WELCOMEPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = loginwelcome

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WELCOMEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WELCOMEUIFigure)
        end
    end
end