classdef myscore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        MYSCORELabel  matlab.ui.control.Label
        total         matlab.ui.control.Label
        levlabel      matlab.ui.control.Label
        overall       matlab.ui.control.Label
        behind        matlab.ui.control.Label
        above         matlab.ui.control.Label
        rank          matlab.ui.control.Label
        level         matlab.ui.control.DropDown
        your          matlab.ui.control.Label
        highest       matlab.ui.control.Label
        behindme      matlab.ui.control.Label
        aboveme       matlab.ui.control.Label
        rankey        matlab.ui.control.Label
        level_2       matlab.ui.control.Label
        subject       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global classcat
            
            classcat = getappdata(0,'classcat')
            setappdata(0,'classcat',classcat)


          try
            dr = [pwd,'\' classcat '\Subject']
            jj = dir(dr)
            gg = []
            for i = 1:length(jj)
                bg = string(jj(i).name)
                kg = regexp(bg,'\.', 'split')
                jui = strcmp(kg(2),'mat')
                if jui == 1
                    gg = [gg kg(1)]
%                 else
%                     continue
                end
               
            end
            gg = [{''} gg]
            app.subject.Items = gg
          catch
              msgbox('No subject available','Contact Admin','help')
          end
        end

        % Value changed function: level
        function levelValueChanged(app, event)
            global classcat
            
            value = app.level.Value;
            valu = app.subject.Value
     if isempty(valu) == 1
                msgbox('Pick a subject','Error','error')
            else
            
            kalup = [valu 'Result.mat']
            load([pwd,'\' classcat '\' kalup]);
            ghg = string(result(1,:))
            fj = find(ghg == value)
 
            ghj = result(2:end,fj)
            jg = []
            
            for k = 1:length(ghj)
                gkh = string(ghj{k})
                jg = [jg gkh]
            end
            jg = rmmissing(jg)


            jjj = []
            for i = 1: length(jg)
            gh = str2num(jg(i))
            jjj = [jjj gh]
            end


            score = sort(jjj)
            rank = sort(jjj,'descend')
            regn = getappdata(0,'reg')
            high = max(score)
            
            
            gm = string(result(:, 3))
            gmm = find(gm == regn)
            mugl = result{gmm,fj}
            mugl = str2num(mugl)
            htl = find(score == mugl)
         if length(score) == 1
                msgbox('Wait for others to participate to see other data','Check Later','help')
                app.your.Text = string(mugl)
            else
               if length(htl) > 1
                htl = htl(1)
                
                rankk = find(rank == mugl)
                  if htl == 1
                answe = 'You scored the least score'
                app.behindme.Text = string(answe)
                
                jhtl = htl + 1
                answ = score(jhtl)
                app.aboveme.Text = string(answ)
                app.your.Text = string(mugl)
                app.rankey.Text = string(rankk)
                app.highest.Text = string(high)
                   elseif htl == length(score)
                
                jtop = htl -1
                answe = score(jtop)
                app.behindme.Text = string(answe)
                
                answ = 'You have the highest score for now'
                app.aboveme.Text = answ
                app.rankey.Text = string(rankk)
                app.your.Text = string(mugl)
                app.highest.Text = string(high)
                   else
                
                jtop = htl -1
                answe = score(jtop)
                app.behindme.Text = string(answe)
                
                jhtl = htl + 1
                answ = score(jhtl)
                app.aboveme.Text = string(answ)
                app.rankey.Text = string(rankk)
                app.your.Text = string(mugl)
                app.highest.Text = string(high)
                   end
                
              elseif length(htl)== 1
                htl = find(score == mugl)
                rankk = find(rank == mugl)
                  if htl == 1
                answe = 'You scored the least score'
                app.behindme.Text = string(answe)
                
                jhtl = htl + 1
                answ = score(jhtl)
                app.aboveme.Text = string(answ)
                app.your.Text = string(mugl)
                app.rankey.Text = string(rankk)
                app.highest.Text = string(high)
                  elseif htl == length(score)
                
                jtop = htl -1
                answe = score(jtop)
                app.behindme.Text = string(answe)
                
                answ = 'You have the highest score for now'
                app.aboveme.Text = answ
                app.rankey.Text = string(rankk)
                app.your.Text = string(mugl)
                app.highest.Text = string(high)
                  else
                
                jtop = htl -1
                answe = score(jtop)
                app.behindme.Text = string(answe)
                
                jhtl = htl + 1
                answ = score(jhtl)
                app.aboveme.Text = string(answ)
                app.rankey.Text = string(rankk)
                app.your.Text = string(mugl)
                app.highest.Text = string(high)
                  end 
              end
         end
    end
        end

        % Value changed function: subject
        function subjectValueChanged(app, event)
            value = app.subject.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.MYSCORELabel = uilabel(app.Panel);
            app.MYSCORELabel.Text = 'MY SCORE';
            app.total = uilabel(app.Panel);
            app.total.Text = 'YOUR SCORE:';
            app.levlabel = uilabel(app.Panel);
            app.levlabel.Text = 'LEVEL:';
            app.overall = uilabel(app.Panel);
            app.overall.Text = 'OVERALL HIGHEST SCORE:';
            app.behind = uilabel(app.Panel);
            app.behind.Text = 'BEHIND ME:';
            app.above = uilabel(app.Panel);
            app.above.Text = 'ABOVE ME:';
            app.rank = uilabel(app.Panel);
            app.rank.Text = 'RANK:';
            app.level = uidropdown(app.Panel);
            app.level.Items = {'Select Practice Level', 'PRACTICE 1', 'PRACTICE 2', 'PRACTICE 3', 'PRACTICE 4', 'PRACTICE 5'};
            app.level.ValueChangedFcn = createCallbackFcn(app, @levelValueChanged, true);
            app.level.Value = 'Select Practice Level';
            app.your = uilabel(app.Panel);
            app.highest = uilabel(app.Panel);
            app.behindme = uilabel(app.Panel);
            app.aboveme = uilabel(app.Panel);
            app.rankey = uilabel(app.Panel);
            app.level_2 = uilabel(app.Panel);
            app.level_2.Text = 'SUBJECT:';
            app.subject = uidropdown(app.Panel);
            app.subject.Items = {'Select Subject'};
            app.subject.ValueChangedFcn = createCallbackFcn(app, @subjectValueChanged, true);
            app.subject.Value = 'Select Subject';
        end
    end

    methods (Access = public)

        % Construct app
        function app = myscore

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end