classdef question < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        homepage          matlab.ui.container.Panel
        Label             matlab.ui.control.Label
        text              matlab.ui.control.TextArea
        EditField_3       matlab.ui.control.EditField
        EditField_2       matlab.ui.control.EditField
        EditField_6       matlab.ui.control.EditField
        EditField_5       matlab.ui.control.EditField
        EditField_7       matlab.ui.control.EditField
        ans_a             matlab.ui.control.CheckBox
        ans_b             matlab.ui.control.CheckBox
        ans_c             matlab.ui.control.CheckBox
        ans_d             matlab.ui.control.CheckBox
        ans_e             matlab.ui.control.CheckBox
        pre               matlab.ui.control.Button
        next              matlab.ui.control.Button
        submit            matlab.ui.control.Button
        pane              matlab.ui.container.Panel
        t1                matlab.ui.control.Button
        t2                matlab.ui.control.Button
        t3                matlab.ui.control.Button
        t4                matlab.ui.control.Button
        t5                matlab.ui.control.Button
        t6                matlab.ui.control.Button
        t7                matlab.ui.control.Button
        t8                matlab.ui.control.Button
        t9                matlab.ui.control.Button
        t10               matlab.ui.control.Button
        t11               matlab.ui.control.Button
        t12               matlab.ui.control.Button
        t13               matlab.ui.control.Button
        t14               matlab.ui.control.Button
        t15               matlab.ui.control.Button
        t16               matlab.ui.control.Button
        t17               matlab.ui.control.Button
        t18               matlab.ui.control.Button
        t19               matlab.ui.control.Button
        t20               matlab.ui.control.Button
        t21               matlab.ui.control.Button
        t22               matlab.ui.control.Button
        t23               matlab.ui.control.Button
        t24               matlab.ui.control.Button
        t25               matlab.ui.control.Button
        t30               matlab.ui.control.Button
        t26               matlab.ui.control.Button
        t27               matlab.ui.control.Button
        t28               matlab.ui.control.Button
        t29               matlab.ui.control.Button
        t35               matlab.ui.control.Button
        t31               matlab.ui.control.Button
        t32               matlab.ui.control.Button
        t33               matlab.ui.control.Button
        t34               matlab.ui.control.Button
        t40               matlab.ui.control.Button
        t36               matlab.ui.control.Button
        t37               matlab.ui.control.Button
        t38               matlab.ui.control.Button
        t39               matlab.ui.control.Button
        t45               matlab.ui.control.Button
        t41               matlab.ui.control.Button
        t42               matlab.ui.control.Button
        t43               matlab.ui.control.Button
        t44               matlab.ui.control.Button
        t46               matlab.ui.control.Button
        t47               matlab.ui.control.Button
        t48               matlab.ui.control.Button
        t49               matlab.ui.control.Button
        t50               matlab.ui.control.Button
        qlabel            matlab.ui.control.Label
        qu                matlab.ui.control.Label
        of                matlab.ui.control.Label
        outofLabel        matlab.ui.control.Label
        checksolution     matlab.ui.control.Button
        YourscoreisLabel  matlab.ui.control.Label
        result            matlab.ui.control.Label
        MYSCOREButton     matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        min               matlab.ui.control.Label
        sec               matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        explanation       matlab.ui.control.EditField
        STARTButton       matlab.ui.control.Button
    end


    properties (Access = public)
       % Description
    end

    properties (Access = private)
      jj; % Description
    end

    methods (Access = private)
    
        function results = time(app)
            timetick;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global qq loop alldata optdisp ght noql classcat
            
            alldata = getappdata(0,'alldata');
            classcat = getappdata(0,'classcat');
            
            app.checksolution.Visible = 'Off';
      
           app.t1.Visible = 'Off';
           app.t2.Visible = 'Off';
           app.t3.Visible = 'Off';
           app.t4.Visible = 'Off';
           app.t5.Visible = 'Off';
           app.t6.Visible = 'Off';
           app.t7.Visible = 'Off';
           app.t8.Visible = 'Off';
           app.t9.Visible = 'Off';
           app.t10.Visible = 'Off';
           app.t11.Visible = 'Off';
           app.t12.Visible = 'Off';
           app.t13.Visible = 'Off';
           app.t14.Visible = 'Off';
           app.t15.Visible = 'Off';
           app.t16.Visible = 'Off';
           app.t17.Visible = 'Off';
           app.t18.Visible = 'Off';
           app.t19.Visible = 'Off';
           app.t20.Visible = 'Off';
           app.t21.Visible = 'Off';
           app.t22.Visible = 'Off';
           app.t23.Visible = 'Off';
           app.t24.Visible = 'Off';
           app.t25.Visible = 'Off';
           app.t26.Visible = 'Off';
           app.t27.Visible = 'Off';
           app.t28.Visible = 'Off';
           app.t29.Visible = 'Off';
           app.t30.Visible = 'Off';
           app.t31.Visible = 'Off';
           app.t32.Visible = 'Off';
           app.t33.Visible = 'Off';
           app.t34.Visible = 'Off';
           app.t35.Visible = 'Off';
           app.t36.Visible = 'Off';
           app.t37.Visible = 'Off';
           app.t38.Visible = 'Off';
           app.t39.Visible = 'Off';
           app.t40.Visible = 'Off';
           app.t41.Visible = 'Off';
           app.t42.Visible = 'Off';
           app.t43.Visible = 'Off';
           app.t44.Visible = 'Off';
           app.t45.Visible = 'Off';
           app.t46.Visible = 'Off';
           app.t47.Visible = 'Off';
           app.t48.Visible = 'Off';
           app.t49.Visible = 'Off';
           app.t50.Visible = 'Off';
            
           
           ght = getappdata(0,'Practice')
           
           
           if strcmp(ght,'PRACTICE')
               value = getappdata(0, 'val')
               app.homepage.Title = 'PRACTICE'
               app.checksolution.Visible = 'On'
               noql = 10
               optdisp = [1,{''}]
            for i = 2: noql
                optd = [i,{''}]
                optdisp = [optdisp; optd]
            end
           
           elseif strcmp(ght,'CHALLENGE')
                value = getappdata(0,'valup')
                app.homepage.Title = 'CHALLENGE'
                noql = getappdata(0, 'noquest')
                optdisp = [1,{''}]
            for i = 2: noql
                optd = [i,{''}]
                optdisp = [optdisp; optd]
            end
            
           end


            app.ans_a.Value = 0
            app.ans_b.Value = 0
            app.ans_c.Value = 0
            app.ans_d.Value = 0
            app.ans_e.Value = 0
            
            
          if strcmp(value,'ANY')
            for li = 1:noql
                jj = strcat('t',string(li));
                app.(jj).Visible = 'On';
            end
            
                
            var = getappdata(0, 'variables');
            
            len = length(var)
            ques = randperm(len, noql)
            qq = []
            for i = 1: length(ques)
                any = var(ques(i))
                qq = [qq any]
            end
            app.text.Value = qq(1)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(1))
            
            a= randperm(5)
            list = [3,5,7,9,11]
            app.ans_a.Text = string(alldata(vg,list(a(1))))
            app.ans_b.Text = string(alldata(vg,list(a(2))))
            app.ans_c.Text = string(alldata(vg,list(a(3))))
            app.ans_d.Text = string(alldata(vg,list(a(4))))
            app.ans_e.Text = string(alldata(vg,list(a(5))))
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            app.of.Text = string(noql)
            
            loop = 1
            app.qu.Text = '1'


          elseif strcmp(value,'TOPIC')


            for li = 1:noql
                jj = strcat('t',string(li))
                app.(jj).Visible = 'On'
            end
                
                mm = getappdata(0, 'var')
%                 noql = getappdata(0, 'noquest'
                jm = length(mm)
                if jm < noql
                    msgbox('Questions not enough for this topic')
                    delete(app)
                else
                jk = randperm(jm, noql)
                qq = []
            for i = 1:length(jk)
                any = mm(jk(i))
                qq = [qq any]
            end
            app.text.Value = qq(1)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(1))
            a= randperm(5)
            list = [3,5,7,9,11]
            app.ans_a.Text = string(alldata(vg,list(a(1))))
            app.ans_b.Text = string(alldata(vg,list(a(2))))
            app.ans_c.Text = string(alldata(vg,list(a(3))))
            app.ans_d.Text = string(alldata(vg,list(a(4))))
            app.ans_e.Text = string(alldata(vg,list(a(5))))
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            app.of.Text = string(noql)
            loop = 1
            app.qu.Text = '1'
                end
%                end
                
          elseif strcmp(value,'YEAR')


            for li = 1:noql
                jj = strcat('t',string(li))
                app.(jj).Visible = 'On'
            end
                
                
                mm = getappdata(0, 'var')
%                 noql = getappdata(0, 'noquest')
               
                jm = length(mm)
                if jm < noql
                    msgbox('Questions not enough for this year')
                    delete(app)
                else
                jk = randperm(jm, noql)
                qq = []
            for i = 1:length(jk)
                any = mm(jk(i))
                qq = [qq any]
            end
            app.text.Value = qq(1)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(1))
            a= randperm(5)
            list = [3,5,7,9,11]
            app.ans_a.Text = string(alldata(vg,list(a(1))))
            app.ans_b.Text = string(alldata(vg,list(a(2))))
            app.ans_c.Text = string(alldata(vg,list(a(3))))
            app.ans_d.Text = string(alldata(vg,list(a(4))))
            app.ans_e.Text = string(alldata(vg,list(a(5))))
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            app.of.Text = string(noql)
            loop = 1
            app.qu.Text = '1'
                end
              
         elseif strcmp(value,'SOURCES')
                  
             
            for li = 1:noql
                jj = strcat('t',string(li))
                app.(jj).Visible = 'On'
            end
                  
            mm = getappdata(0, 'var')
  
                jm = length(mm)
                if jm < noql
                    msgbox('Questions not enough for this source')
                    delete(app)
                else
                jk = randperm(jm, noql)
                qq = []
            for i = 1:length(jk)
                any = mm(jk(i))
                qq = [qq any]
            end
            app.text.Value = qq(1)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(1))
            
            a= randperm(5)
            list = [3,5,7,9,11]
            app.ans_a.Text = string(alldata(vg,list(a(1))))
            app.ans_b.Text = string(alldata(vg,list(a(2))))
            app.ans_c.Text = string(alldata(vg,list(a(3))))
            app.ans_d.Text = string(alldata(vg,list(a(4))))
            app.ans_e.Text = string(alldata(vg,list(a(5))))
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            app.of.Text = string(noql/2)
            loop = 1
            app.qu.Text = '1'
                end
          end
            
%             app.of.Text = '30'
        end

        % Value changed function: ans_a
        function ans_aValueChanged(app, event)
            global optdisp alldata
            alldata = string(alldata)
            
            
             a = app.ans_a.Value
             a = string(a)
             if strcmp(a,'true')
                 
                 tex = app.text.Value
                 ans_text = app.ans_a.Text
                 jp=find(alldata(:,1)==tex);
                 mp = find(alldata(jp,:)==ans_text)
                 v = mp-1
            
                 anst = alldata(jp,v)
                 
                 
                 app.ans_b.Value = 0
                 app.ans_c.Value = 0
                 app.ans_d.Value = 0
                 app.ans_e.Value = 0
                 
                 b = app.qu.Text
                 button = strcat('t', b)       
            app.(button).BackgroundColor ='b'
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == b)
            optdisp(gmj,2) = {anst}
            
            
             elseif strcmp(a,'false')
                 b = app.qu.Text
                 button = strcat('t', b)       
            app.(button).BackgroundColor = [0.96 0.96 0.96]
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == b)
            optdisp(gmj,2) = {''}
            
             end

        end

        % Value changed function: ans_b
        function ans_bValueChanged(app, event)
            global optdisp alldata
            alldata = string(alldata)
            b = app.ans_b.Value
             b = string(b)
             if strcmp(b,'true')
                 
                 app.ans_a.Value = 0
                 app.ans_c.Value = 0
                 app.ans_d.Value = 0
                 app.ans_e.Value = 0
                 
                 tex = app.text.Value
                 ans_text = app.ans_a.Text
                 jp=find(alldata(:,1)==tex);
                 mp = find(alldata(jp,:)==ans_text)
                 v = mp-1
            
                 anst = alldata(jp,v)
                 
                 
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor ='b'
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {anst}
            
             elseif strcmp(b,'false')
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor = [0.96 0.96 0.96]
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {''}
            
             end
%             app.ans_1.Value = 0
%             app.t1.Value = 0
%             app.t3.Value = 0
%             app.t4.Value = 0
        end

        % Value changed function: ans_c
        function ans_cValueChanged(app, event)
%             app.t1.Value = 0
%             app.ans_1.Value = 0
%             app.t3.Value = 0
%             app.t4.Value = 0
                  global optdisp alldata
                  alldata = string(alldata)
            c = app.ans_c.Value
             c = string(c)
             if strcmp(c,'true')
                 
                 app.ans_b.Value = 0
                 app.ans_a.Value = 0
                 app.ans_d.Value = 0
                 app.ans_e.Value = 0
                 
                 
                 tex = app.text.Value
                 ans_text = app.ans_a.Text
                 jp=find(alldata(:,1)==tex);
                 mp = find(alldata(jp,:)==ans_text)
                 v = mp-1
            
                 anst = alldata(jp,v)
                 
                 
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor ='b'
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {anst}
            
             elseif strcmp(c,'false')
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor = [0.96 0.96 0.96]
            
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {''}
             end
        end

        % Value changed function: ans_d
        function ans_dValueChanged(app, event)
%             app.t1.Value = 0
%             app.t1.Value = 0
%             app.ans_1.Value = 0
%             app.t4.Value = 0

               global optdisp alldata
               alldata = string(alldata)
            d = app.ans_d.Value
             d = string(d)
             if strcmp(d,'true')
                 
                 app.ans_a.Value = 0
                 app.ans_b.Value = 0
                 app.ans_c.Value = 0
                 app.ans_e.Value = 0
                 
                 tex = app.text.Value
                 ans_text = app.ans_a.Text
                 jp=find(alldata(:,1)==tex);
                 mp = find(alldata(jp,:)==ans_text)
                 v = mp-1
            
                 anst = alldata(jp,v)
                 
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor ='b'
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {anst}
            
             elseif strcmp(d,'false')
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor = [0.96 0.96 0.96]
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {''}
             end
        end

        % Value changed function: ans_e
        function ans_eValueChanged(app, event)
%             app.t1.Value = 0
%             app.t1.Value = 0
%             app.t3.Value = 0
%             app.ans_1.Value = 0

             global optdisp alldata
             alldata = string(alldata);
             e = app.ans_e.Value
             e = string(e)
             if strcmp(e,'true')
                 
                 app.ans_b.Value = 0
                 app.ans_c.Value = 0
                 app.ans_d.Value = 0
                 app.ans_a.Value = 0
                 
                 
                 tex = app.text.Value
                 ans_text = app.ans_a.Text
                 jp=find(alldata(:,1)==tex);
                 mp = find(alldata(jp,:)==ans_text)
                 v = mp-1
            
                 anst = alldata(jp,v)
                 
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor ='b'
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {anst}
            
             elseif strcmp(e,'false')
                 cat = app.qu.Text
                 button = strcat('t', cat)       
            app.(button).BackgroundColor = [0.96 0.96 0.96]
            
            optdip = string(optdisp(:,1))
            gmj = find(optdip == cat)
            optdisp(gmj,2) = {''}
             end
        end

        % Callback function
        function loadButtonPushed(app, event)
            
        end

        % Callback function
        function setupButtonPushed(app, event)
            
        end

        % Callback function
        function startPushed(app, event)
           
            
        end

        % Callback function
        function subjectValueChanged(app, event)
            
        end

        % Callback function
        function topicValueChanged(app, event)
            
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            global qq alldata optdisp
         
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            p = app.qu.Text
            q = app.of.Text
     try
               
%             button = strcat('t', string(loop-1))       
%             app.(button).BackgroundColor ='b'
            jk = app.qu.Text
            jk = str2num(jk)
            jk = jk+1
            jk = string(jk)
            app.qu.Text = jk
            jk = str2num(jk)
             app.text.Value = qq(jk)
             
             pap = app.text.Value
           row = string(alldata(1:end,1));
            
            viq = find(row == pap)
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(viq,list(a(1))));
            app.ans_b.Text = string(alldata(viq,list(a(2))));
            app.ans_c.Text = string(alldata(viq,list(a(3))));
            app.ans_d.Text = string(alldata(viq,list(a(4))));
            app.ans_e.Text = string(alldata(viq,list(a(5))));
            
            app.ans_a.Text = ans1
            app.ans_b.Text = ans2
            app.ans_c.Text = ans3
            app.ans_d.Text = ans4
            app.ans_e.Text = ans5
            
            
             app.ans_a.Value = 0
            app.ans_b.Value = 0
            app.ans_c.Value = 0
            app.ans_d.Value = 0
            app.ans_e.Value = 0
            
            opt = string(alldata(1:end,1))
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
            
   catch
               msgbox('No more Questions')
               app.qu.Text = q
   end
        end

        % Button pushed function: pre
        function preButtonPushed(app, event)
            global alldata qq optdisp
%             mn = app.qu.Text
%             vn = str2num(23)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
           try 
            jk = app.qu.Text
            jk = str2num(jk)
            jk = jk-1
            jk = string(jk)
            app.qu.Text = jk
            jk =  str2num(jk)
              app.text.Value = qq(jk)
              
              pap = app.text.Value
           row = string(alldata(1:end,1));
            
           viq = find(row == pap)
           
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(viq,list(a(1))));
            app.ans_b.Text = string(alldata(viq,list(a(2))));
            app.ans_c.Text = string(alldata(viq,list(a(3))));
            app.ans_d.Text = string(alldata(viq,list(a(4))));
            app.ans_e.Text = string(alldata(viq,list(a(5))));
            
            app.ans_a.Text = ans1
            app.ans_b.Text = ans2
            app.ans_c.Text = ans3
            app.ans_d.Text = ans4
            app.ans_e.Text = ans5
            
            
            app.ans_a.Value = 0
            app.ans_b.Value = 0
            app.ans_c.Value = 0
            app.ans_d.Value = 0
            app.ans_e.Value = 0
            
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab


            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
            
            
           catch
               msgbox('No previous Question')
               app.qu.Text = '1'
           end


        end

        % Callback function
        function yearValueChanged(app, event)
            value = app.year.Value;
            app.start.Enable = 'On'
        end

        % Callback function
        function TestButtonPushed(app, event)
           i = 't20'
           app.(i).Visible = 'On'
        end

        % Size changed function: homepage
        function homepageSizeChanged(app, event)
            position = app.homepage.Position;
            
           
        end

        % Button pushed function: t1
        function t1ButtonPushed(app, event)
            global qq alldata optdisp
            
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]


            jmk = app.t1.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(1)
            opt = string(alldata(1:end,1))
            
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab


            %vg = find(opt == qq(1))
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t2
        function t2ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]


            jmk = app.t2.Text
            app.qu.Text = jmk
           
            app.text.Value = qq(2)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(2))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t3
        function t3ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t3.Text
            app.qu.Text = jmk
           
            app.text.Value = qq(3)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(3))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));


            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t4
        function t4ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t4.Text
            app.qu.Text = jmk


            app.text.Value = qq(4)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(4))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t5
        function t5ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t5.Text
            app.qu.Text = jmk


            app.text.Value = qq(5)
            opt = string(alldata(1:end,1))
            
            
            vg = find(opt == qq(5))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t6
        function t6ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t6.Text
            app.qu.Text = jmk


            app.text.Value = qq(6)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(6))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t7
        function t7ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t7.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(7)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(7))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t8
        function t8ButtonPushed(app, event)
           app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t8.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
           
            app.text.Value = qq(8)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(8))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t9
        function t9ButtonPushed(app, event)
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t9.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(9)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(9))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t10
        function t10ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t10.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            app.text.Value = qq(10)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(10))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
             bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t11
        function t11ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t11.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(11)
            opt = string(alldata(1:end,1))
            
            vg = find(opt == qq(11))
            
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vg,list(a(1))));
            app.ans_b.Text = string(alldata(vg,list(a(2))));
            app.ans_c.Text = string(alldata(vg,list(a(3))));
            app.ans_d.Text = string(alldata(vg,list(a(4))));
            app.ans_e.Text = string(alldata(vg,list(a(5))));
            
            lab1 = string(alldata(vg,12))
            lab2 = string(alldata(vg,13))
            lab3 = string(alldata(vg,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t12
        function t12ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t12.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
          
            
            app.text.Value = qq(12)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab


            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t13
        function t13ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t13.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            
            app.text.Value = qq(13)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t14
        function t14ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t14.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
          
            
            app.text.Value = qq(14)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t15
        function t15ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t15.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(15)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t16
        function t16ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t16.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            
            app.text.Value = qq(16)
            opt = string(alldata(1:end,1))
            
           bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t17
        function t17ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t17.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(17)
            opt = string(alldata(1:end,1))
            
           bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t18
        function t18ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t18.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(18)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t19
        function t19ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t19.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(19)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t20
        function t20ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t20.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(20)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t21
        function t21ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t21.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
            
            app.text.Value = qq(21)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t22
        function t22ButtonPushed(app, event)
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t22.Text
            app.qu.Text = jmk
            
            global qq alldata optdisp
           
            app.text.Value = qq(22)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t23
        function t23ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t23.Text
            app.qu.Text = jmk


            app.text.Value = qq(23)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t24
        function t24ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t24.Text
            app.qu.Text = jmk
                
            app.text.Value = qq(24)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t25
        function t25ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t25.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(25)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t26
        function t26ButtonPushed(app, event)
           global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t26.Text
            app.qu.Text = jmk
          
            app.text.Value = qq(26)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t27
        function t27ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t27.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(27)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t28
        function t28ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t28.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(28)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t29
        function t29ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t29.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(29)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab


            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t30
        function t30ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t30.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(30)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
     
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t31
        function t31ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t31.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(31)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t32
        function t32ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t32.Text
            app.qu.Text = jmk
           
            app.text.Value = qq(32)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t33
        function t33ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t33.Text
            app.qu.Text = jmk
           
            app.text.Value = qq(33)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t34
        function t34ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t34.Text
            app.qu.Text = jmk
           
            app.text.Value = qq(34)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t35
        function t35ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t35.Text
            app.qu.Text = jmk
          
            app.text.Value = qq(35)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t36
        function t36ButtonPushed(app, event)
           global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t36.Text
            app.qu.Text = jmk
            
            app.text.Value = qq(36)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t37
        function t37ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t37.Text
            app.qu.Text = jmk
           
            app.text.Value = qq(37)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t38
        function t38ButtonPushed(app, event)
           global qq alldata optdisp
           
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t38.Text
            app.qu.Text = jmk


            app.text.Value = qq(38)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t39
        function t39ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            
            jmk = app.t39.Text
            app.qu.Text = jmk


            app.text.Value = qq(39)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t40
        function t40ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t40.Text
            app.qu.Text = jmk
          
            app.text.Value = qq(40)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t41
        function t41ButtonPushed(app, event)
           global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t41.Text
            app.qu.Text = jmk


            app.text.Value = qq(41)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t42
        function t42ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t42.Text
            app.qu.Text = jmk


            app.text.Value = qq(42)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t43
        function t43ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t43.Text
            app.qu.Text = jmk


            app.text.Value = qq(43)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t44
        function t44ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t44.Text
            app.qu.Text = jmk


            app.text.Value = qq(44)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t45
        function t45ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t45.Text
            app.qu.Text = jmk


            app.text.Value = qq(45)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
           if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
           end
        end

        % Button pushed function: t46
        function t46ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t46.Text
            app.qu.Text = jmk


            app.text.Value = qq(46)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t47
        function t47ButtonPushed(app, event)
             global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t47.Text
            app.qu.Text = jmk
            
            
            app.text.Value = qq(47)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t48
        function t48ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t48.Text
            app.qu.Text = jmk
            
            
            app.text.Value = qq(48)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t49
        function t49ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t49.Text
            app.qu.Text = jmk
            
            
            app.text.Value = qq(49)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: t50
        function t50ButtonPushed(app, event)
            global qq alldata optdisp
            
            app.ans_a.FontColor = [0 0 0]
            app.ans_b.FontColor = [0 0 0]
            app.ans_c.FontColor = [0 0 0]
            app.ans_d.FontColor = [0 0 0]
            app.ans_e.FontColor = [0 0 0]
            
            jmk = app.t50.Text
            app.qu.Text = jmk
           
            
            app.text.Value = qq(50)
            opt = string(alldata(1:end,1))
            
            bbk = app.text.Value
            vvd = find(opt == bbk)
            
            a= randperm(5);
            list = [3,5,7,9,11];
            app.ans_a.Text = string(alldata(vvd,list(a(1))));
            app.ans_b.Text = string(alldata(vvd,list(a(2))));
            app.ans_c.Text = string(alldata(vvd,list(a(3))));
            app.ans_d.Text = string(alldata(vvd,list(a(4))));
            app.ans_e.Text = string(alldata(vvd,list(a(5))));
            
            lab1 = string(alldata(vvd,12))
            lab2 = string(alldata(vvd,13))
            lab3 = string(alldata(vvd,14))
            lab = strcat(lab1, lab2, '(', lab3, ')')
            % lab = [lab1 ' ' lab2 '(' lab3 ')']
            app.qlabel.Text = lab
            
            
            bol = app.qu.Text
            optdip = string(optdisp(:,1))
            gmj = find(optdip == bol)
            mql = string(optdisp(gmj,2))
            if strcmp(mql,'')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(A)')
                app.ans_a.Value = 1
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(B)')
                app.ans_a.Value = 0
                app.ans_b.Value = 1
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql, '(C)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 1
                app.ans_d.Value = 0
                app.ans_e.Value = 0
            elseif strcmp(mql,'(D)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 1
                app.ans_e.Value = 0
            elseif strcmp(mql,'(E)')
                app.ans_a.Value = 0
                app.ans_b.Value = 0
                app.ans_c.Value = 0
                app.ans_d.Value = 0
                app.ans_e.Value = 1
            end
        end

        % Button pushed function: checksolution
        function checksolutionPushed(app, event)
           global alldata qq
           
           jh = string(alldata(:,1))
            bht = app.qu.Text
            bht = str2num(bht)
            mgh = find(jh == qq(bht))
            ans = alldata{mgh,16}
            if strcmp(ans, '(A)')
                app.ans_a.FontColor = [0 1 0]
            elseif strcmp(ans,'(B)')
                app.ans_b.FontColor = [0 1 0]
            elseif strcmp(ans, '(C)')
                app.ans_c.FontColor = [0 1 0]
             elseif strcmp(ans, '(D)')
                app.ans_d.FontColor = [0 1 0]
             elseif strcmp(ans, '(E)')
                app.ans_e.FontColor = [0 1 0]
            end
        end

        % Button pushed function: submit
        function submitButtonPushed(app, event)
            global alldata qq optdisp ght classcat
            
            sub = getappdata(0,'gbu')
            sub = [sub 'Result']
            load([pwd '\' classcat '\' sub])
            
            vgb = string(alldata(1:end,1));
            mgh = app.text.Value
            pqr = optdisp(:,:)
            rsv = string(optdisp(:,:))
            a = 0
            for i = 1: length(qq)
                vku = find(vgb == qq(i))
                ghj = string(alldata(vku,16))
                fgr = string(optdisp(i,2))
%                 vtu = strcmp(ghj,fgr)
                if strcmp(ghj, fgr)
                    a = a+1
                elseif strcmp(fgr,' ')
                    a = a+0
                else
                    a = a-1
                end
%                 a = a+a
            end
            %msgbox(['Your score is' ' ' num2str(a)])
            app.result.Text = num2str(a)
            app.checksolution.Enable = 'On'
            
            regn = getappdata(0,'reg')
            mgy = string(result(1,:))
            mjk = string(result(:,3))
            vj = find(mjk == regn)
            if strcmp(ght,'PRACTICE')
                mk = getappdata(0,'gage')
                if isempty(mk) == 0
                    
                    mk = string(mk)
                    mk = str2mat(mk)
                    ghy = [str2mat(ght) ' ' mk]
                    yy = find(mgy == string(ghy))
                
                result{vj,yy} = num2str(a);
                save([pwd '\' classcat '\' sub], 'result')
                    
                else
                    return
                end
                
            elseif strcmp(ght,'CHALLENGE')   
                k = 'CHALLENGE'
                
                ghy = [str2mat(k)]
                yy = find(mgy == string(ghy))
                
                result{vj,yy} = num2str(a);
                save([pwd '\' classcat '\' sub], 'result')
            end

            rmappdata(0,'gage')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            submitButtonPushed(app, event)
            pause(2)
            delete(app)
        end

        % Button pushed function: MYSCOREButton
        function MYSCOREButtonPushed(app, event)
            delete(app)
            myscore
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global noql
            app.STARTButton.Visible = 'Off'
            app.homepage.Visible = 'On'
%             timetick
             a = noql/2
             time = a*60;
        for i = 1: time
            bc = time/60;
            str_f = sprintf('%0.3f',bc);
            mint=regexp(str_f, '\.', 'split');
            min = mint(1)
            sec = rem(time,60);
            min = string(min);
            min = str2num(min);
            min = sprintf('%02d',min);
            sec = sprintf('%02d',sec);
            app.min.Text = min
            app.sec.Text = sec
            time = time-1;
            pause(1)
       end
            submitButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.homepage = uipanel(app.UIFigure);
            app.homepage.Title = 'HOME_PAGE';
            app.homepage.SizeChangedFcn = createCallbackFcn(app, @homepageSizeChanged, true);
            app.Label = uilabel(app.homepage);
            app.Label.Text = '';
            app.text = uitextarea(app.homepage);
            app.text.Editable = 'off';
            app.EditField_3 = uieditfield(app.homepage, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'C';
            app.EditField_2 = uieditfield(app.homepage, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'B';
            app.EditField_6 = uieditfield(app.homepage, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'D';
            app.EditField_5 = uieditfield(app.homepage, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'E';
            app.EditField_7 = uieditfield(app.homepage, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'A';
            app.ans_a = uicheckbox(app.homepage);
            app.ans_a.ValueChangedFcn = createCallbackFcn(app, @ans_aValueChanged, true);
            app.ans_a.Text = '';
            app.ans_b = uicheckbox(app.homepage);
            app.ans_b.ValueChangedFcn = createCallbackFcn(app, @ans_bValueChanged, true);
            app.ans_b.Text = '';
            app.ans_c = uicheckbox(app.homepage);
            app.ans_c.ValueChangedFcn = createCallbackFcn(app, @ans_cValueChanged, true);
            app.ans_c.Text = '';
            app.ans_d = uicheckbox(app.homepage);
            app.ans_d.ValueChangedFcn = createCallbackFcn(app, @ans_dValueChanged, true);
            app.ans_d.Text = '';
            app.ans_e = uicheckbox(app.homepage);
            app.ans_e.ValueChangedFcn = createCallbackFcn(app, @ans_eValueChanged, true);
            app.ans_e.Text = '';
            app.pre = uibutton(app.homepage, 'push');
            app.pre.ButtonPushedFcn = createCallbackFcn(app, @preButtonPushed, true);
            app.pre.Text = '←';
            app.next = uibutton(app.homepage, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = '→';
            app.submit = uibutton(app.homepage, 'push');
            app.submit.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submit.Text = 'SUBMIT';
            app.pane = uipanel(app.homepage);
            app.t1 = uibutton(app.pane, 'push');
            app.t1.ButtonPushedFcn = createCallbackFcn(app, @t1ButtonPushed, true);
            app.t1.Text = '1';
            app.t2 = uibutton(app.pane, 'push');
            app.t2.ButtonPushedFcn = createCallbackFcn(app, @t2ButtonPushed, true);
            app.t2.Text = '2';
            app.t3 = uibutton(app.pane, 'push');
            app.t3.ButtonPushedFcn = createCallbackFcn(app, @t3ButtonPushed, true);
            app.t3.Text = '3';
            app.t4 = uibutton(app.pane, 'push');
            app.t4.ButtonPushedFcn = createCallbackFcn(app, @t4ButtonPushed, true);
            app.t4.Text = '4';
            app.t5 = uibutton(app.pane, 'push');
            app.t5.ButtonPushedFcn = createCallbackFcn(app, @t5ButtonPushed, true);
            app.t5.Text = '5';
            app.t6 = uibutton(app.pane, 'push');
            app.t6.ButtonPushedFcn = createCallbackFcn(app, @t6ButtonPushed, true);
            app.t6.Text = '6';
            app.t7 = uibutton(app.pane, 'push');
            app.t7.ButtonPushedFcn = createCallbackFcn(app, @t7ButtonPushed, true);
            app.t7.Text = '7';
            app.t8 = uibutton(app.pane, 'push');
            app.t8.ButtonPushedFcn = createCallbackFcn(app, @t8ButtonPushed, true);
            app.t8.Text = '8';
            app.t9 = uibutton(app.pane, 'push');
            app.t9.ButtonPushedFcn = createCallbackFcn(app, @t9ButtonPushed, true);
            app.t9.Text = '9';
            app.t10 = uibutton(app.pane, 'push');
            app.t10.ButtonPushedFcn = createCallbackFcn(app, @t10ButtonPushed, true);
            app.t10.Text = '10';
            app.t11 = uibutton(app.pane, 'push');
            app.t11.ButtonPushedFcn = createCallbackFcn(app, @t11ButtonPushed, true);
            app.t11.Text = '11';
            app.t12 = uibutton(app.pane, 'push');
            app.t12.ButtonPushedFcn = createCallbackFcn(app, @t12ButtonPushed, true);
            app.t12.Text = '12';
            app.t13 = uibutton(app.pane, 'push');
            app.t13.ButtonPushedFcn = createCallbackFcn(app, @t13ButtonPushed, true);
            app.t13.Text = '13';
            app.t14 = uibutton(app.pane, 'push');
            app.t14.ButtonPushedFcn = createCallbackFcn(app, @t14ButtonPushed, true);
            app.t14.Text = '14';
            app.t15 = uibutton(app.pane, 'push');
            app.t15.ButtonPushedFcn = createCallbackFcn(app, @t15ButtonPushed, true);
            app.t15.Text = '15';
            app.t16 = uibutton(app.pane, 'push');
            app.t16.ButtonPushedFcn = createCallbackFcn(app, @t16ButtonPushed, true);
            app.t16.Text = '16';
            app.t17 = uibutton(app.pane, 'push');
            app.t17.ButtonPushedFcn = createCallbackFcn(app, @t17ButtonPushed, true);
            app.t17.Text = '17';
            app.t18 = uibutton(app.pane, 'push');
            app.t18.ButtonPushedFcn = createCallbackFcn(app, @t18ButtonPushed, true);
            app.t18.Text = '18';
            app.t19 = uibutton(app.pane, 'push');
            app.t19.ButtonPushedFcn = createCallbackFcn(app, @t19ButtonPushed, true);
            app.t19.Text = '19';
            app.t20 = uibutton(app.pane, 'push');
            app.t20.ButtonPushedFcn = createCallbackFcn(app, @t20ButtonPushed, true);
            app.t20.Text = '20';
            app.t21 = uibutton(app.pane, 'push');
            app.t21.ButtonPushedFcn = createCallbackFcn(app, @t21ButtonPushed, true);
            app.t21.Text = '21';
            app.t22 = uibutton(app.pane, 'push');
            app.t22.ButtonPushedFcn = createCallbackFcn(app, @t22ButtonPushed, true);
            app.t22.Text = '22';
            app.t23 = uibutton(app.pane, 'push');
            app.t23.ButtonPushedFcn = createCallbackFcn(app, @t23ButtonPushed, true);
            app.t23.Text = '23';
            app.t24 = uibutton(app.pane, 'push');
            app.t24.ButtonPushedFcn = createCallbackFcn(app, @t24ButtonPushed, true);
            app.t24.Text = '24';
            app.t25 = uibutton(app.pane, 'push');
            app.t25.ButtonPushedFcn = createCallbackFcn(app, @t25ButtonPushed, true);
            app.t25.Text = '25';
            app.t30 = uibutton(app.pane, 'push');
            app.t30.ButtonPushedFcn = createCallbackFcn(app, @t30ButtonPushed, true);
            app.t30.Text = '30';
            app.t26 = uibutton(app.pane, 'push');
            app.t26.ButtonPushedFcn = createCallbackFcn(app, @t26ButtonPushed, true);
            app.t26.Text = '26';
            app.t27 = uibutton(app.pane, 'push');
            app.t27.ButtonPushedFcn = createCallbackFcn(app, @t27ButtonPushed, true);
            app.t27.Text = '27';
            app.t28 = uibutton(app.pane, 'push');
            app.t28.ButtonPushedFcn = createCallbackFcn(app, @t28ButtonPushed, true);
            app.t28.Text = '28';
            app.t29 = uibutton(app.pane, 'push');
            app.t29.ButtonPushedFcn = createCallbackFcn(app, @t29ButtonPushed, true);
            app.t29.Text = '29';
            app.t35 = uibutton(app.pane, 'push');
            app.t35.ButtonPushedFcn = createCallbackFcn(app, @t35ButtonPushed, true);
            app.t35.Text = '35';
            app.t31 = uibutton(app.pane, 'push');
            app.t31.ButtonPushedFcn = createCallbackFcn(app, @t31ButtonPushed, true);
            app.t31.Text = '31';
            app.t32 = uibutton(app.pane, 'push');
            app.t32.ButtonPushedFcn = createCallbackFcn(app, @t32ButtonPushed, true);
            app.t32.Text = '32';
            app.t33 = uibutton(app.pane, 'push');
            app.t33.ButtonPushedFcn = createCallbackFcn(app, @t33ButtonPushed, true);
            app.t33.Text = '33';
            app.t34 = uibutton(app.pane, 'push');
            app.t34.ButtonPushedFcn = createCallbackFcn(app, @t34ButtonPushed, true);
            app.t34.Text = '34';
            app.t40 = uibutton(app.pane, 'push');
            app.t40.ButtonPushedFcn = createCallbackFcn(app, @t40ButtonPushed, true);
            app.t40.Text = '40';
            app.t36 = uibutton(app.pane, 'push');
            app.t36.ButtonPushedFcn = createCallbackFcn(app, @t36ButtonPushed, true);
            app.t36.Text = '36';
            app.t37 = uibutton(app.pane, 'push');
            app.t37.ButtonPushedFcn = createCallbackFcn(app, @t37ButtonPushed, true);
            app.t37.Text = '37';
            app.t38 = uibutton(app.pane, 'push');
            app.t38.ButtonPushedFcn = createCallbackFcn(app, @t38ButtonPushed, true);
            app.t38.Text = '38';
            app.t39 = uibutton(app.pane, 'push');
            app.t39.ButtonPushedFcn = createCallbackFcn(app, @t39ButtonPushed, true);
            app.t39.Text = '39';
            app.t45 = uibutton(app.pane, 'push');
            app.t45.ButtonPushedFcn = createCallbackFcn(app, @t45ButtonPushed, true);
            app.t45.Text = '45';
            app.t41 = uibutton(app.pane, 'push');
            app.t41.ButtonPushedFcn = createCallbackFcn(app, @t41ButtonPushed, true);
            app.t41.Text = '41';
            app.t42 = uibutton(app.pane, 'push');
            app.t42.ButtonPushedFcn = createCallbackFcn(app, @t42ButtonPushed, true);
            app.t42.Text = '42';
            app.t43 = uibutton(app.pane, 'push');
            app.t43.ButtonPushedFcn = createCallbackFcn(app, @t43ButtonPushed, true);
            app.t43.Text = '43';
            app.t44 = uibutton(app.pane, 'push');
            app.t44.ButtonPushedFcn = createCallbackFcn(app, @t44ButtonPushed, true);
            app.t44.Text = '44';
            app.t46 = uibutton(app.pane, 'push');
            app.t46.ButtonPushedFcn = createCallbackFcn(app, @t46ButtonPushed, true);
            app.t46.Text = '46';
            app.t47 = uibutton(app.pane, 'push');
            app.t47.ButtonPushedFcn = createCallbackFcn(app, @t47ButtonPushed, true);
            app.t47.Text = '47';
            app.t48 = uibutton(app.pane, 'push');
            app.t48.ButtonPushedFcn = createCallbackFcn(app, @t48ButtonPushed, true);
            app.t48.Text = '48';
            app.t49 = uibutton(app.pane, 'push');
            app.t49.ButtonPushedFcn = createCallbackFcn(app, @t49ButtonPushed, true);
            app.t49.Text = '49';
            app.t50 = uibutton(app.pane, 'push');
            app.t50.ButtonPushedFcn = createCallbackFcn(app, @t50ButtonPushed, true);
            app.t50.Text = '50';
            app.qlabel = uilabel(app.homepage);
            app.qu = uilabel(app.homepage);
            app.qu.Text = '';
            app.of = uilabel(app.homepage);
            app.of.Text = '';
            app.outofLabel = uilabel(app.homepage);
            app.outofLabel.Text = 'out of';
            app.checksolution = uibutton(app.homepage, 'push');
            app.checksolution.ButtonPushedFcn = createCallbackFcn(app, @checksolutionPushed, true);
            app.checksolution.Enable = 'off';
            app.checksolution.Text = 'ANSWER';
            app.YourscoreisLabel = uilabel(app.homepage);
            app.YourscoreisLabel.Text = 'Your score is:';
            app.result = uilabel(app.homepage);
            app.result.Text = '';
            app.MYSCOREButton = uibutton(app.homepage, 'push');
            app.MYSCOREButton.ButtonPushedFcn = createCallbackFcn(app, @MYSCOREButtonPushed, true);
            app.MYSCOREButton.Text = 'MY SCORE';
            app.UIAxes = uiaxes(app.homepage);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Panel = uipanel(app.homepage);
            app.min = uilabel(app.Panel);
            app.min.Text = 'Label2';
            app.sec = uilabel(app.Panel);
            app.sec.Text = 'Label2';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ':';
            app.explanation = uieditfield(app.UIFigure, 'text');
            app.explanation.Editable = 'off';
            app.explanation.Enable = 'off';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = question

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end