classdef takepicture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        axes                           matlab.ui.control.UIAxes
        TAKEPROFILEPICTUREButton       matlab.ui.control.Button
        STARTPICTUREFORTRAININGButton  matlab.ui.control.Button
        upload                         matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        DontforgettouploadyourprofilepictureLabel  matlab.ui.control.Label
        MakesureyouarefocusedonthecameraforeasyverificationLabel  matlab.ui.control.Label
        lab                            matlab.ui.control.EditField
        DeleteButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        vid;
        videoInputNumber = 1;
        numberofpictures = 3;
        time = 1;
        i;
        trainfeat;
    end

    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            name = getappdata(0,'name')
            setappdata(0,'name', name)
            try
            delete(app.vid)
            catch
            end
        end

        % Button pushed function: TAKEPROFILEPICTUREButton
        function TAKEPROFILEPICTUREButtonPushed(app, event)
%            camera = webcam
%             im=snapshot(camera)
        %facedetector=vision.CascadeObjectDetector;
         app.vid=videoinput('winvideo',1,'YUY2_640x480')
         triggerconfig(app.vid,'manual');
         set(app.vid,'FramesPerTrigger',1);
%          set(app.vid,'TriggerRepeat',inf);
         start(app.vid);
         trigger(app.vid);
         imgdata=getdata(app.vid,1);
         
%          snaping = getsnapshot(app.vid);
         imshow(ycbcr2rgb(imgdata),'Parent',app.axes)  
         delete(app.vid)
         
%          msgbox('Make sure you upload your desired profile picture')
%          movefile (namep, 'Profile_Pictures')
% %              end
        end

        % Button pushed function: STARTPICTUREFORTRAININGButton
        function STARTPICTUREFORTRAININGButtonPushed(app, event)
            picname = getappdata(0,'regn') 
            app.vid=videoinput('winvideo',1,'YUY2_640x480')
            triggerconfig(app.vid,'manual');
            set(app.vid,'FramesPerTrigger',1);
            set(app.vid,'TriggerRepeat',inf);
            start(app.vid);
            %waitTime=2;
            facedetector=vision.CascadeObjectDetector;
         %i=0;
         %numofpictures = 4
             trainfeat = []
             trigger(app.vid)
             imgdata=getdata(app.vid,1)
             for i = 1:app.numberofpictures
                 snaping = getsnapshot(app.vid)
                 bboxes=step(facedetector,snaping)
                  for j=1:size(bboxes,1)
                      img=imcrop(snaping,bboxes(j,:));
                      imshow(ycbcr2rgb(img),'Parent',app.axes);
                  end
%                   imagey = 
                 grayimg=rgb2gray(getimage(app.axes)); % Convert the image to gray scale [value 0-255]);
                 resizeimg=imresize(grayimg,[128,128]);
                 features=extractLBPFeatures(resizeimg)
                 trainfeat=[trainfeat;features]
                 pause(app.time)
                 app.i = app.i + 1
             end
             
              
%               vq = regexp(picname,'/','split')
%               namep = strcat(vq(1),'_',vq(2),'_',vq(3))
              picname = string(picname)
              namep = str2mat(picname)
              namesave = [namep '.mat']
              save(namesave,'trainfeat'); 
              movefile(namesave, 'Faces')
                
              msgbox('Trained Successfully');
                 
               delete(app.vid)
        end

        % Button pushed function: upload
        function uploadPushed(app, event)
            labe = app.upload.Text
            app.lab.Value = labe
            
            
            profile = getimage(app.axes)
            picname = getappdata(0,'regn')
            picname = string(picname)
%             vq = regexp(picname,'/','split')
%          namep = strcat(vq(1),'_',vq(2),'_',vq(3))
         namep = str2mat(picname)
         imwrite(profile,sprintf('%s.jpg',namep))
         movefile([namep '.jpg'], 'Profile_Pictures')
         
         msgbox('Picture upload successful')
         delete(app.vid)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            labe = app.lab.Value
            
            if isempty(labe)==1
                msgbox('You must upload your profile picture','Error','error')
            else
                congratulations
                delete(app)
            end


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete(app.vid)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.axes = uiaxes(app.Panel);
            title(app.axes, 'Picture')
            app.axes.XTick = [];
            app.axes.XTickLabel = {'[ ]'};
            app.axes.YTick = [];
            app.axes.YTickLabel = {'[ ]'};
            app.TAKEPROFILEPICTUREButton = uibutton(app.Panel, 'push');
            app.TAKEPROFILEPICTUREButton.ButtonPushedFcn = createCallbackFcn(app, @TAKEPROFILEPICTUREButtonPushed, true);
            app.TAKEPROFILEPICTUREButton.Text = 'TAKE PROFILE PICTURE';
            app.STARTPICTUREFORTRAININGButton = uibutton(app.Panel, 'push');
            app.STARTPICTUREFORTRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTPICTUREFORTRAININGButtonPushed, true);
            app.STARTPICTUREFORTRAININGButton.Text = 'START PICTURE FOR TRAINING';
            app.upload = uibutton(app.Panel, 'push');
            app.upload.ButtonPushedFcn = createCallbackFcn(app, @uploadPushed, true);
            app.upload.Text = 'UPLOAD PROFILE PICTURE';
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DontforgettouploadyourprofilepictureLabel = uilabel(app.Panel);
            app.DontforgettouploadyourprofilepictureLabel.Text = 'Don''t forget to upload your profile picture ';
            app.MakesureyouarefocusedonthecameraforeasyverificationLabel = uilabel(app.Panel);
            app.MakesureyouarefocusedonthecameraforeasyverificationLabel.Text = 'Make sure you are focused on the camera for easy verification';
            app.lab = uieditfield(app.Panel, 'text');
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
        end
    end

    methods (Access = public)

        % Construct app
        function app = takepicture

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end