classdef validation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        VALIDATIONPanel  matlab.ui.container.Panel
        axes             matlab.ui.control.UIAxes
        takepicture      matlab.ui.control.Button
        validate         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global regn
            regn = getappdata(0,'regn')
            setappdata(0,'regn',regn)
        end

        % Button pushed function: takepicture
        function takepictureButtonPushed(app, event)
            vid=videoinput('winvideo',1,'YUY2_640x480')
            triggerconfig(vid,'manual');
            set(vid,'FramesPerTrigger',1);
            set(vid,'TriggerRepeat',inf);
            start(vid);
            numofpictures=1;
            waitTime=2;
            facedetector=vision.CascadeObjectDetector;
             trigger(vid);
             imgdata=getdata(vid,1);
             %imshow(ycbcr2rgb(imgdata),'Parent',app.axes)
             snapimg=getsnapshot(vid);
             bboxes=step(facedetector,snapimg)
        for j=1:size(bboxes,1)
           img=imcrop(snapimg,bboxes(j,:));
           imshow(ycbcr2rgb(img),'Parent',app.axes);
        end
% % delete the object
           delete(vid);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: validate
        function validateButtonPushed(app, event)
            global regn
            
            testimg=getimage(app.axes);
%             resizeimg=imresize(testimg,[128,128]);
            grayimg=rgb2gray(testimg); % Convert the color image to gray image
            resizeimg=imresize(grayimg,[128,128]);
            feature=extractLBPFeatures(resizeimg)
            
            
%             vq = regexp(regn,'/','split')
%             namep = strcat(vq(1),'_',vq(2),'_',vq(3))
            regn = string(regn)
            namep = str2mat(regn)
            name = [namep '.mat']
            load([pwd '\Faces' '\' name])
            TrF=trainfeat; % Train Features
            [row,col]=size(TrF);
 
            score=inf;
            match=-1;
            
            for p=1:length(row)
                D(p,:)=abs(TrF(p,:)-feature);
                s=mean(D(p,:));
                   if s<score
                      match=p;
                      score=s;
                   end
            end
 
 
                  matching_score=score
%                   db=dir('*.jpg');

            if matching_score<0.032
    
               img=imread([pwd,'\','Profile_Pictures', '\' [namep '.jpg']]);
               imshow(img, 'Parent', app.axes);
               msgbox('Recognized Successfully');
               delete(app)
               Challenge
            else
               cla(app.axes,'reset');
               cla(app.axes2,'reset');
               msgbox('Not Recognized');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VALIDATIONPanel = uipanel(app.UIFigure);
            app.VALIDATIONPanel.Title = 'VALIDATION';
            app.axes = uiaxes(app.VALIDATIONPanel);
            title(app.axes, 'Title')
            app.axes.XTick = [];
            app.axes.XTickLabel = '';
            app.axes.YTick = [];
            app.takepicture = uibutton(app.VALIDATIONPanel, 'push');
            app.takepicture.ButtonPushedFcn = createCallbackFcn(app, @takepictureButtonPushed, true);
            app.takepicture.Text = 'TAKE PICTURE';
            app.validate = uibutton(app.VALIDATIONPanel, 'push');
            app.validate.ButtonPushedFcn = createCallbackFcn(app, @validateButtonPushed, true);
            app.validate.Text = 'VALIDATE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = validation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end